classdef App_SnapVac3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        Image2                    matlab.ui.control.Image
        FocalPoint_Panel          matlab.ui.container.Panel
        FocalPoint_GridLayout     matlab.ui.container.GridLayout
        FocalPoint_Delete_Button  matlab.ui.control.Button
        FocalPoint_Add_Button     matlab.ui.control.Button
        FocalPoint_UITable        matlab.ui.control.Table
        Port_Panel                matlab.ui.container.Panel
        Port_GridLayout           matlab.ui.container.GridLayout
        Port_Delete_Button        matlab.ui.control.Button
        Port_DeleteRowN_Button    matlab.ui.control.Button
        Port_Add_Button           matlab.ui.control.Button
        Port_UITable              matlab.ui.control.Table
        Chamber_Panel             matlab.ui.container.Panel
        Chamber_GridLayout        matlab.ui.container.GridLayout
        Chamber_UITable           matlab.ui.control.Table
        AppTitle                  matlab.ui.control.Label
        AppButtons_GridLayout     matlab.ui.container.GridLayout
        ViewModel_4Panel_Button   matlab.ui.control.Button
        ViewModel_1Panel_Button   matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        SAVEAS_Button             matlab.ui.control.Button
        LOAD_Button               matlab.ui.control.Button
        TRANSFER_Button           matlab.ui.control.Button
        APPINFO_Button            matlab.ui.control.Button
        RESIZEUI_Button           matlab.ui.control.Button
        RESTART_Button            matlab.ui.control.Button
        CLOSEFIGS_Button          matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        myData                  % Data Structure containing all App variables
        table_chamber           % Table of chamber properties
        table_focalpoints       % Table of focal-points
        table_ports             % Table of port properties
        num_of_ports            % Number of ports
        num_of_focalpoints      % Number of focal-points
        color_of_focalpoints    % Colour of each focal-point
    end

    % Private methods available to the app only
    methods (Access = private)
        % Extract all the possible body types
        function body_types = extract_body_types(app)
            body_types = {...
                'Cylindrical',...
                'Spherical',...
                'Tetragonal',...
                };
        end  

        % Extract all the possible flange sizes
        function flange_types = extract_flange_types(app)
            flange_types = {...
                'DN10CF',...
                'DN16CF',...
                'DN25CF',...
                'DN40CF',...
                'DN50CF',...
                'DN63CF',...
                'DN75CF',...
                'DN100CF',...
                'DN125CF',...
                'DN160CF',...
                'DN200CF',...
                'DN250CF',...
                };
        end  
        
        % Extract results for rotatable or tapped flanges
        function binary_types = extract_binary_types(app)
            binary_types = {...
                'yes',...
                'no',...
                };
        end  
        
        % Extract results for rotatable or tapped flanges
        function focal_types = extract_focal_types(app)
            Alphabet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
            for i = 1:app.num_of_focalpoints
                focal_types{i} = Alphabet(i);
            end
        end  

        % Update the myData structure
        function update_myData(app)
            app.myData.TimeStamp            = datetime;
            app.myData.FileName             = string(app.table_chamber.Name{1});
            app.myData.Notes                = string(app.table_chamber.Notes{1});
            app.myData.table_chamber        = app.table_chamber;
            app.myData.table_focalpoints    = app.table_focalpoints;
            app.myData.table_ports          = app.table_ports;
            app.myData.num_of_focalpoints   = app.num_of_focalpoints;
            app.myData.num_of_ports         = app.num_of_ports;
            app.myData.color_of_focalpoints = app.color_of_focalpoints;
        end  

        % Extract port data and plot the surface
        function extract_and_plot_main(app, type, x0, y0, z0, diameter, length)
            % 1 - Extract & plot the main body
            if strcmpi(type, "Cylindrical")
                % -- Create vectors for a unit cylinder
                [x, y, z] = cylinder; 
                % -- Scale cylinder to the desired dimensions
                x   = 0.5*diameter.*x - x0;
                y   = 0.5*diameter.*y - y0;
                z   = length.*(z-0.5) - z0;
                % -- Plot the cylinder
                surf(x, y, z);
            elseif strcmpi(type, "Spherical")
                % -- Create vectors for a unit sphere
                [x, y, z] = sphere; 
                % -- Scale sphere to the desired dimensions
                x   = 0.5*diameter.*x - x0;
                y   = 0.5*diameter.*y - y0;
                z   = 0.5*diameter.*z - z0;
                % -- Plot the sphere
                surf(x, y, z);
            elseif strcmpi(type, "Tetragonal")
                % -- Calculate half of the edge length to shift the cube's center to the origin
                halfEdge    = diameter / 2;
                halfHeight  = length / 2;
                % -- Create vectors for the cube's vertices, centered at the origin
                x           = [-halfEdge, halfEdge];
                y           = [-halfEdge, halfEdge];
                z           = [-halfHeight, halfHeight];
                % -- Create meshgrids for the six faces of the cube
                [X1, Y1]    = meshgrid(x, y); % Bottom and top faces
                [Z2, X2]    = meshgrid(z, x); % Front and back faces
                [Z3, Y3]    = meshgrid(z, y); % Left and right faces
                % -- Plot the square
                % --- Bottom face
                surf(X1, Y1, -halfHeight * ones(size(X1)));
                % --- Top face
                surf(X1, Y1, halfHeight * ones(size(X1)));
                % --- Front face
                surf(X2, -halfEdge * ones(size(X2)), Z2);
                % --- Back face
                surf(X2, halfEdge * ones(size(X2)), Z2);
                % --- Left face
                surf(-halfEdge * ones(size(Y3)), Y3, Z3);
                % --- Right face
                surf(halfEdge * ones(size(Y3)), Y3, Z3);
            end
        end

        % Extract port data and plot the surface
        function extract_and_plot_port(app, x0, y0, z0, theta, phi, flange, length, rotatable, tapped)
            % 1 - Extract the flange diameter
            flange_diameter = regexp(flange, '\d+', 'match');
            flange_diameter = str2double(flange_diameter{1});
            % 2 - Exract the rotation matrix
            theta   = -deg2rad(theta);
            phi     = deg2rad(phi);
            % -- Rotation matrix around z-axis by phi
            Rz      = [cos(phi) -sin(phi) 0;
                        sin(phi) cos(phi) 0;
                        0 0 1];
            % -- Rotation matrix around the y-axis by theta
            Ry      = [cos(theta) 0 sin(theta);
                        0 1 0;
                        -sin(theta) 0 cos(theta)];
            % -- Combined rotation matrix (first Ry, then Rz)
            R = Rz * Ry;
            % 3 - Extract the port tube coordinates
            % 3.1 - Create cylinder and scale to correct size
            [y, z, x]           = cylinder; 
            x                   = length.*x; 
            y                   = 0.5*flange_diameter.*y;
            z                   = 0.5*flange_diameter.*z;
            % -- Apply the rotation matrix 
            cylinder_coords     = [x(:) y(:) z(:)];
            rotated_coords      = (R * cylinder_coords')';
            % -- Reshape the rotated coordinates back into 2D arrays
            x_rot               = reshape(rotated_coords(:,1), size(x));
            y_rot               = reshape(rotated_coords(:,2), size(y));
            z_rot               = reshape(rotated_coords(:,3), size(z));
            % -- Displace cylinder to new origin
            x_tube              = x_rot + x0;
            y_tube              = y_rot + y0;
            z_tube              = z_rot + z0;

            % 4 - Extract the flange coordinates
            % 4.1 - Create cylinder and scale to correct size
            [y, z, x]   = cylinder; 
            x           = 5.*(x-1); 
            y           = (0.6*flange_diameter).*y;
            z           = (0.6*flange_diameter).*z;
            % -- Apply the rotation matrix 
            cylinder_coords     = [x(:) y(:) z(:)];
            rotated_coords      = (R * cylinder_coords')';
            % -- Reshape the rotated coordinates back into 2D arrays
            x_rot       = reshape(rotated_coords(:,1), size(x));
            y_rot       = reshape(rotated_coords(:,2), size(y));
            z_rot       = reshape(rotated_coords(:,3), size(z));
            % -- Displace cylinder to new origin
            x_shift     = length.*sin(pi/2-theta).*cos(phi);
            y_shift     = length.*sin(pi/2-theta).*sin(phi);
            z_shift     = length.*cos(pi/2-theta);
            % -- Final flange coordinates
            x_flange    = x_rot + x0 + x_shift;
            y_flange    = y_rot + y0 + y_shift;
            z_flange    = z_rot + z0 - z_shift;
            % 5 - Plot the result
            surf(x_tube, y_tube, z_tube);
            surf(x_flange, y_flange, z_flange);
            if strcmpi(rotatable, "yes") && strcmpi(tapped, "yes")
                text(1.05*x_shift, 1.05*y_shift, -1.05*z_shift, "RT", 'FontSize',12);
            elseif strcmpi(rotatable, "yes")
                text(1.05*x_shift, 1.05*y_shift, -1.05*z_shift, "R", 'FontSize',12);
            elseif strcmpi(tapped, "yes")
                text(1.05*x_shift, 1.05*y_shift, -1.05*z_shift, "T", 'FontSize',12);
            end
        end

        % Loading in data
        function extract_and_plot_final_model(app)
            % 1 - Extracting all the input arguments
            % 1.1 - Chamber Properties
            FileName            = app.myData.table_chamber{1,1}{1};
            MainBody            = app.myData.table_chamber{1,2}{1};
            OuterDiameter       = app.myData.table_chamber{1,3}{1};
            Wall                = app.myData.table_chamber{1,4}{1};
            LengthMainBody      = app.myData.table_chamber{1,5}{1};
            Notes               = app.myData.table_chamber{1,6}{1};
            % 1.2 - Focal-Point Properties
            FP = {};
            for i = 1:app.num_of_focalpoints
                FP{i} = [app.myData.table_focalpoints{i,3}{1}, app.myData.table_focalpoints{i,4}{1}, app.myData.table_focalpoints{i,5}{1}];
            end
            FP_cols = app.color_of_focalpoints;
            % 1.3 - Port Properties
            Port                = cell2mat(app.myData.table_ports{:,1});
            Flange              = app.myData.table_ports{:,2};
            Length              = cell2mat(app.myData.table_ports{:,3});
            Theta               = cell2mat(app.myData.table_ports{:,4});
            Phi                 = cell2mat(app.myData.table_ports{:,5});
            Rotatable           = app.myData.table_ports{:,6};
            Tapped              = app.myData.table_ports{:,7};
            Usage               = app.myData.table_ports{:,8};
            FPP                 = app.myData.table_ports{:,9};
            % 1.4 - Finding the maximum dimension & axes limits
            axLim = 1.2*OuterDiameter;
            % - Plot the axes
            plot3([0 axLim], [0 0], [0 0], 'r', 'LineWidth', 2); % X-axis in blue
            plot3([0 0], [0 axLim], [0 0], 'b', 'LineWidth', 2); % Y-axis in red
            plot3([0 0], [0 0], [0 axLim], 'g', 'LineWidth', 2); % Z-axis in green
            % - Setting the labels
            text(0.95*axLim, 0.01*axLim, 0.01*axLim, 'X', 'Color', 'r', 'FontSize', 12);
            text(0.01*axLim, 0.95*axLim, 0.01*axLim, 'Y', 'Color', 'b', 'FontSize', 12);
            text(0.01*axLim, 0.01*axLim, 0.95*axLim, 'Z', 'Color', 'g', 'FontSize', 12);
            % -- Plot the main body
            extract_and_plot_main(app, MainBody, FP{1}(1), FP{1}(2), FP{1}(3), OuterDiameter, LengthMainBody);
            % -- Plot the ports
            focal_types = extract_focal_types(app);
            for i = 1:app.num_of_ports
                [~, idx]        = ismember(FPP{i}, focal_types);
                extract_and_plot_port(app, FP{idx}(1), FP{idx}(2), FP{idx}(3), Theta(i), Phi(i), Flange{i}, Length(i), Rotatable{i}, Tapped{i});
            end
            % - Plot the focal points
            for i = 1:app.num_of_focalpoints
                plot3(FP{i}(1), FP{i}(2), FP{i}(3), 'ks', 'color', FP_cols(i,:), 'MarkerFaceColor', FP_cols(i,:), 'markersize', 12);
                line(xlim+FP{i}(1), [0,0]+FP{i}(2), [0,0]+FP{i}(3), 'LineStyle', ':', 'LineWidth', 1, 'Color', FP_cols(i,:));
                line([0,0]+FP{i}(1), ylim+FP{i}(2), [0,0]+FP{i}(3), 'LineStyle', ':', 'LineWidth', 1, 'Color', FP_cols(i,:));
                line([0,0]+FP{i}(1), [0,0]+FP{i}(2), zlim+FP{i}(3), 'LineStyle', ':', 'LineWidth', 1, 'Color', FP_cols(i,:));
            end
            % Set the surface color to gray
            colormap([0.7 0.7 0.7]);
            % Add lighting and shading to enhance the 3D effect
            shading faceted;
            camlight right;
            camlight left;
            lighting gouraud;
        end

        function view_final_chamber_1panel(app)
            % Initialising the 1-panel figure
            fig = figure();
            fig.Position(1) = 60;
            fig.Position(2) = 60;
            fig.Position(3) = 750;
            fig.Position(4) = 750;
            hold on;
            extract_and_plot_final_model(app);
            % - Formatting the axis
            title(app.myData.FileName, 'interpreter','none', 'FontWeight', 'bold');
            xlabel('X (mm)', 'FontWeight', 'bold');
            ylabel('Y (mm)', 'FontWeight', 'bold');
            zlabel('Z (mm)', 'FontWeight', 'bold');
            axis equal; 
            axis off; set(gca, 'Color', 'none'); grid off;
            view([-222,32]);
            % -- Pad the axes limits
            xLimits = xlim; yLimits = ylim; zLimits = zlim;
            xRange = xLimits(2) - xLimits(1); yRange = yLimits(2) - yLimits(1); zRange = zLimits(2) - zLimits(1);
            newXLimits = [xLimits(1) - 0.1*xRange, xLimits(2) + 0.1*xRange];
            newYLimits = [yLimits(1) - 0.1*yRange, yLimits(2) + 0.1*yRange];
            newZLimits = [zLimits(1) - 0.1*zRange, zLimits(2) + 0.1*zRange];
            xlim(newXLimits); ylim(newYLimits); zlim(newZLimits);
        end

        % Plot final chamber
        function view_final_chamber_4panel(app)
            % Initialising the 4-panel figure
            fig = figure();
            fig.Position(1) = 60;
            fig.Position(2) = 60;
            fig.Position(3) = 750;
            fig.Position(4) = 750;
            % Create a tiled layout with 2 rows and 2 columns
            t = tiledlayout(2, 2, 'TileSpacing', 'compact', 'Padding', 'compact');
            title(t, app.myData.FileName, 'interpreter','none', 'FontWeight', 'bold');
            % 3D plot of the vacuum chamber
            nexttile; hold on;
            extract_and_plot_final_model(app);
            % - Formatting the axis
            title('3D View');
            axis equal;
            axis off; set(gca, 'Color', 'none'); grid off;
            view([-222,32]);
            % X-Y plane
            nexttile; hold on;
            extract_and_plot_final_model(app);
            title('X-Y Plane');
            xlabel('X (mm)', 'FontWeight', 'bold');
            ylabel('Y (mm)', 'FontWeight', 'bold');
            zlabel('Z (mm)', 'FontWeight', 'bold');
            axis equal; grid on;
            view(0, 90);
            % -- Pad the axes limits
            xLimits = xlim; yLimits = ylim; zLimits = zlim;
            xRange = xLimits(2) - xLimits(1); yRange = yLimits(2) - yLimits(1); zRange = zLimits(2) - zLimits(1);
            newXLimits = [xLimits(1) - 0.1*xRange, xLimits(2) + 0.1*xRange];
            newYLimits = [yLimits(1) - 0.1*yRange, yLimits(2) + 0.1*yRange];
            newZLimits = [zLimits(1) - 0.1*zRange, zLimits(2) + 0.1*zRange];
            xlim(newXLimits); ylim(newYLimits); zlim(newZLimits);
            % Z-Y plane
            nexttile; hold on;
            extract_and_plot_final_model(app);
            title('Z-Y Plane');
            xlabel('X (mm)', 'FontWeight', 'bold');
            ylabel('Y (mm)', 'FontWeight', 'bold');
            zlabel('Z (mm)', 'FontWeight', 'bold');
            axis equal; grid on;
            view(90, 0);
            % -- Pad the axes limits
            xLimits = xlim; yLimits = ylim; zLimits = zlim;
            xRange = xLimits(2) - xLimits(1); yRange = yLimits(2) - yLimits(1); zRange = zLimits(2) - zLimits(1);
            newXLimits = [xLimits(1) - 0.1*xRange, xLimits(2) + 0.1*xRange];
            newYLimits = [yLimits(1) - 0.1*yRange, yLimits(2) + 0.1*yRange];
            newZLimits = [zLimits(1) - 0.1*zRange, zLimits(2) + 0.1*zRange];
            xlim(newXLimits); ylim(newYLimits); zlim(newZLimits);
            % Z-X plane
            nexttile; hold on;
            extract_and_plot_final_model(app);
            title('Z-X Plane');
            xlabel('X (mm)', 'FontWeight', 'bold');
            ylabel('Y (mm)', 'FontWeight', 'bold');
            zlabel('Z (mm)', 'FontWeight', 'bold');
            axis equal; grid on;
            view(0, 0);
            % -- Pad the axes limits
            xLimits = xlim; yLimits = ylim; zLimits = zlim;
            xRange = xLimits(2) - xLimits(1); yRange = yLimits(2) - yLimits(1); zRange = zLimits(2) - zLimits(1);
            newXLimits = [xLimits(1) - 0.1*xRange, xLimits(2) + 0.1*xRange];
            newYLimits = [yLimits(1) - 0.1*yRange, yLimits(2) + 0.1*yRange];
            newZLimits = [zLimits(1) - 0.1*zRange, zLimits(2) + 0.1*zRange];
            xlim(newXLimits); ylim(newYLimits); zlim(newZLimits);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'SnapVac3D App (procopios.constantinou@psi.ch)';
            % 1 - Initialising App Properties
            app.myData                              = struct();
            app.color_of_focalpoints                = lines(24);
            % 1.1 - Chamber Properties
            default_filename                        = char(string(yyyymmdd(datetime))+"_PSI_VacBuild");
            body_types                              = extract_body_types(app);
            app.Chamber_UITable.ColumnFormat        = {'char', body_types, 'numeric', 'numeric', 'numeric', 'char'};
            app.Chamber_UITable.ColumnName          = {'Name', 'Main-Body Type', 'Outer Diameter (mm)', 'Wall Thickness (mm)', 'Length (mm)', 'Notes'};
            app.Chamber_UITable.ColumnEditable      = [true,true,true,true,true,true];
            app.Chamber_UITable.Data                = {default_filename,body_types{1},100,5,100,''};
            app.table_chamber                       = array2table(app.Chamber_UITable.Data, 'VariableNames', app.Chamber_UITable.ColumnName);
            % 1.2 - Focal-Point Properties
            app.num_of_focalpoints                  = 2;
            app.FocalPoint_UITable.ColumnFormat     = {'char', 'char', 'numeric', 'numeric', 'numeric'};
            app.FocalPoint_UITable.ColumnName       = {'Name', 'Color', 'FPX', 'FPY', 'FPZ'};
            app.FocalPoint_UITable.ColumnEditable   = [false,false,true,true,true];
            app.FocalPoint_UITable.Data             = {'A','',0,0,0; 'B','',-180,0,0};
            app.table_focalpoints                   = array2table(app.FocalPoint_UITable.Data, 'VariableNames', app.FocalPoint_UITable.ColumnName);
            % -- Updating the color cell
            temp_col = uistyle('BackgroundColor', app.color_of_focalpoints(1,:)); addStyle(app.FocalPoint_UITable, temp_col, 'cell', [1, 2]);
            temp_col = uistyle('BackgroundColor', app.color_of_focalpoints(2,:)); addStyle(app.FocalPoint_UITable, temp_col, 'cell', [2, 2]);
            % 1.3 - Port Properties
            app.num_of_ports                        = 1;
            flange_types                            = extract_flange_types(app);
            binary_types                            = extract_binary_types(app);
            focal_types                             = extract_focal_types(app);
            app.Port_UITable.ColumnFormat           = {'numeric', flange_types, 'numeric', 'numeric', 'numeric', binary_types, binary_types, 'numeric', focal_types};
            app.Port_UITable.ColumnName             = {'Port', 'Flange', 'Length', 'Theta (β)', 'Phi (α)', 'Rotatable?', 'Tapped?', 'Usage', 'Focal-Point'};
            app.Port_UITable.ColumnEditable         = [false,true,true,true,true,true,true,true,true];
            app.Port_UITable.Data                   = {1,flange_types{2},196,0,-90,binary_types{1},binary_types{2},'PHOIBOS150',focal_types{1}};
            app.table_ports                         = array2table(app.Port_UITable.Data, 'VariableNames', app.Port_UITable.ColumnName);
            % 2 - Update myData structure
            update_myData(app);
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            % Remove the UI handle and restart
            close(gcbf); run App_SnapVac3D.mlapp;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 1125;
            screen_size(4) = 555;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            disp('.myData : '); disp(app.myData);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            assignin('base', 'myData', app.myData);
        end

        % Button pushed function: LOAD_Button
        function LOAD_ButtonPushed(app, event)
            warning('off', 'all');
            % 1 - Loading in the FileName and PathName of the selected data
            [FileName, PathName] = uigetfile({'*.txt'}, 'Choose a SnapVac3D *.txt data file...');
            load_fullfile = char(string(PathName) + string(FileName));
            % - If Cancel is pressed, then return nothing
            if isequal(PathName,0) || isequal(FileName,0); return; end
            % 2 - Executing the loading of the data
            % -- Read the file line by line and find the indicator
            fid     = fopen(load_fullfile);
            data    = textscan(fid, '%s', 'Delimiter', '\n');
            fclose(fid);
            lines   = data{1};
            idx = {};
            for i = 1:length(lines); if strcmp(lines{i}, '-----'); idx{end+1} = i; end; end
            % -- Extract the tables
            % --- Load in Main-Body Table Information
            opts = detectImportOptions(load_fullfile); 
            opts.DataLines = [2 idx{1}-1];          
            opts.VariableNamesLine = 1;
            firstColumnName = opts.VariableNames{1};            % Get the name of the first column 
            opts = setvartype(opts, firstColumnName, 'char');   % Make sure the first entry is a char
            T1 = readtable(load_fullfile, opts);
            T1(:,7:end) = [];
            % --- Load in Focal-Points Table Information
            opts = detectImportOptions(load_fullfile); 
            opts.DataLines = [idx{1}+2 idx{2}-1];      
            opts.VariableNamesLine = idx{1}+1;
            firstColumnName = opts.VariableNames{1};            % Get the name of the first column 
            opts = setvartype(opts, firstColumnName, 'char');   % Make sure the first entry is a char
            T2 = readtable(load_fullfile, opts);
            T2(:,6:end) = [];
            % --- Load in Ports Table Information
            opts = detectImportOptions(load_fullfile); 
            opts.DataLines = [idx{2}+2 idx{3}-1];      
            opts.VariableNamesLine = idx{2}+1;
            T3 = readtable(load_fullfile, opts);
            % 3 - Updating the tables within the App
            app.num_of_focalpoints  = size(T2, 1);
            app.num_of_ports        = size(T3, 1);
            % 3.1 - Main-Body Table
            app.Chamber_UITable.Data = {T1{1,1}{1},T1{1,2}{1},T1{1,3},T1{1,4},T1{1,5},T1{1,6}{1}};
            app.table_chamber       = array2table(app.Chamber_UITable.Data, 'VariableNames', app.Chamber_UITable.ColumnName);
            % 3.2 - Focal-Point Table
            focal_types     = extract_focal_types(app);
            for i = 1:app.num_of_focalpoints
                app.FocalPoint_UITable.Data(i,:) = {focal_types{i},T2{i,2}{1},T2{i,3},T2{i,4},T2{i,5}};
                temp_col = uistyle('BackgroundColor', app.color_of_focalpoints(i,:)); addStyle(app.FocalPoint_UITable, temp_col, 'cell', [i, 2]);
            end
            if size(app.FocalPoint_UITable.Data,1) > app.num_of_focalpoints
                app.FocalPoint_UITable.Data(app.num_of_focalpoints+1:end,:) = [];
            end
            app.table_focalpoints       = array2table(app.FocalPoint_UITable.Data, 'VariableNames', app.FocalPoint_UITable.ColumnName);
            % 3.3 - Ports Table
            for i = 1:app.num_of_ports
                app.Port_UITable.Data(i,:) = {i,T3{i,2}{1},T3{i,3},T3{i,4},T3{i,5},T3{i,6}{1},T3{i,7}{1},T3{i,8}{1},T3{i,9}{1}};
            end
            if size(app.Port_UITable.Data,1) > app.num_of_ports
                app.Port_UITable.Data(app.num_of_ports+1:end,:) = [];
            end
            app.table_ports             = array2table(app.Port_UITable.Data, 'VariableNames', app.Port_UITable.ColumnName);
            % 4 - Update myData structure
            update_myData(app);
            warning('on', 'all');
        end

        % Button pushed function: SAVEAS_Button
        function SAVEAS_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            fname = app.myData.FileName; filter = {'*.txt'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the design...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...');
            % 2.1 - Assigning each table to a T variable
            T1 = app.myData.table_chamber;
            T2 = app.myData.table_focalpoints;
            T3 = app.myData.table_ports;
            % 2.2 - Appending the data-tables to a text file
            % -- Write the first table to the file (overwrite if the file exists)
            writetable(T1, save_fullfile, 'Delimiter', '\t', 'WriteVariableNames', true);
            % -- Append the separator
            fid = fopen(save_fullfile, 'a');
            fprintf(fid, '-----');
            fclose(fid);
            % -- Append the second table to the file
            writetable(T2, save_fullfile, 'Delimiter', '\t', 'WriteMode', 'append', 'WriteVariableNames', true);
            % -- Append the separator
            fid = fopen(save_fullfile, 'a');
            fprintf(fid, '-----');
            fclose(fid);
            % -- Append the third table to the file
            writetable(T3, save_fullfile, 'Delimiter', '\t', 'WriteMode', 'append', 'WriteVariableNames', true);
            % -- Append the separator
            fid = fopen(save_fullfile, 'a');
            fprintf(fid, '-----');
            fclose(fid);
            % 3 - Executing the saving of a png
            % -- Save 4-panel figure
            view_final_chamber_1panel(app);
            saveas(gcf,save_fullfile(1:end-4) + "_3D", 'png');
            % -- Save 3D figure
            view_final_chamber_4panel(app);
            saveas(gcf,save_fullfile(1:end-4) + "_4-panel", 'png');
            % 4 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Cell edit callback: Chamber_UITable
        function Chamber_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - General Validity check on the input values
            if indices(2) == 3; if newData < 0; newData = abs(newData); end; end
            if indices(2) == 3; if isnan(newData); newData = 0; end; end
            if indices(2) == 4; if newData < 0; newData = abs(newData); end; end
            if indices(2) == 4; if isnan(newData); newData = 0; end; end
            if indices(2) == 5; if newData < 0; newData = abs(newData); end; end
            if indices(2) == 5; if isnan(newData); newData = 0; end; end
            % 3 - Assigning the output
            app.Chamber_UITable.Data{indices(1),indices(2)} = newData;
            app.table_chamber       = array2table(app.Chamber_UITable.Data, 'VariableNames', app.Chamber_UITable.ColumnName);
            % 4 - Printing the output
            if indices(2) == 1;     update_txt = sprintf("-> Name = %s \n", newData);
            elseif indices(2) == 2; update_txt = sprintf("-> Main-Body Type = %s \n", newData);
            elseif indices(2) == 3; update_txt = sprintf("-> Outer-Diameter = %.2f mm \n", newData);
            elseif indices(2) == 4; update_txt = sprintf("-> Wall Thickness = %.2f mm \n",  newData);
            elseif indices(2) == 5; update_txt = sprintf("-> Length = %.2f mm \n",  newData);
            elseif indices(2) == 6; update_txt = sprintf("-> Notes = %s \n",  newData);
            end
            fprintf(update_txt);
            update_myData(app);
        end

        % Button pushed function: FocalPoint_Add_Button
        function FocalPoint_Add_ButtonPushed(app, event)
            % - Adding one new focal-point to the UI table
            if app.num_of_focalpoints ~= 24
                app.num_of_focalpoints      = app.num_of_focalpoints+1;
                focal_types                 = extract_focal_types(app);
                % -- Updating the Focal-Point Values UI Table
                Name                        = focal_types{end};
                Colour                      = '';
                FPX                         = app.FocalPoint_UITable.Data(end,3); FPX = FPX{1};
                FPY                         = app.FocalPoint_UITable.Data(end,4); FPY = FPY{1};
                FPZ                         = app.FocalPoint_UITable.Data(end,5); FPZ = FPZ{1};
                app.FocalPoint_UITable.Data(end+1,:) = {Name, Colour, FPX, FPY, FPZ};
                % -- Updating the color cell
                temp_col = uistyle('BackgroundColor', app.color_of_focalpoints(app.num_of_focalpoints,:)); 
                addStyle(app.FocalPoint_UITable, temp_col, 'cell', [app.num_of_focalpoints, 2]);
                % -- Assigning and printing the output
                app.table_focalpoints       = array2table(app.FocalPoint_UITable.Data, 'VariableNames', app.FocalPoint_UITable.ColumnName);
                fprintf("-> Focal-Point Added: N = " + string(app.num_of_focalpoints) + " \n");
            else; fprintf("-> Currently only supports 24 focal-points maximum...\n");
            end
            % - Update data-structure
            update_myData(app);
        end

        % Button pushed function: FocalPoint_Delete_Button
        function FocalPoint_Delete_ButtonPushed(app, event)
            % - Removing one focal-point from the UI tables
            if app.num_of_focalpoints ~= 1 
                app.num_of_focalpoints = app.num_of_focalpoints - 1;
                app.FocalPoint_UITable.Data(end,:) = []; 
                % -- Assigning and printing the output
                app.table_focalpoints       = array2table(app.FocalPoint_UITable.Data, 'VariableNames', app.FocalPoint_UITable.ColumnName);
                fprintf("-> Focal-Point Deleted: N = " + string(app.num_of_focalpoints) + " \n");
            else; fprintf("-> At least one focal-point is required...\n");
            end
            % - Update data-structure
            update_myData(app);
        end

        % Cell edit callback: FocalPoint_UITable
        function FocalPoint_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - General Validity check on the input values
            if isnan(newData); newData = 0; end
            % 3 - Assigning the output
            app.FocalPoint_UITable.Data{indices(1),indices(2)} = newData;
            app.table_focalpoints       = array2table(app.FocalPoint_UITable.Data, 'VariableNames', app.FocalPoint_UITable.ColumnName);
            % 4 - Printing the output
            focal_types = extract_focal_types(app);
            if indices(2) == 3;     update_txt = sprintf("-> FPX(%s) = %.2f mm \n", focal_types{indices(1)}, newData);
            elseif indices(2) == 4; update_txt = sprintf("-> FPY(%s) = %.2f mm \n", focal_types{indices(1)}, newData);
            elseif indices(2) == 5; update_txt = sprintf("-> FPZ(%s) = %.2f mm \n", focal_types{indices(1)}, newData);
            end
            fprintf(update_txt);
            update_myData(app);
        end

        % Button pushed function: Port_Add_Button
        function Port_Add_ButtonPushed(app, event)
            % - Adding one new port to the UI table
            app.num_of_ports    = app.num_of_ports+1;
            % -- Updating the Ports Values UI Table
            binary_types        = extract_binary_types(app);
            Port                = app.num_of_ports;
            Flange              = app.Port_UITable.Data(end,2); Flange = Flange{1};
            Length              = 100;
            Theta               = 0;
            Phi                = 0;
            Rotatable           = binary_types{2}; 
            Tapped              = binary_types{2}; 
            Usage               = '';
            FP                  = app.Port_UITable.Data(end,9); FP = FP{1};
            app.Port_UITable.Data(end+1,:) = {Port, Flange, Length, Theta, Phi, Rotatable, Tapped, Usage, FP};
            % -- Assigning and printing the output
            app.table_ports     = array2table(app.Port_UITable.Data, 'VariableNames', app.Port_UITable.ColumnName);
            fprintf("-> Port Added: N = " + string(app.num_of_ports) + " \n");
            % - Update data-structure
            update_myData(app);
        end

        % Button pushed function: Port_Delete_Button
        function Port_Delete_ButtonPushed(app, event)
            % - Removing one port from the UI tables
            if app.num_of_ports ~= 1 
                app.num_of_ports = app.num_of_ports - 1;
                app.Port_UITable.Data(end,:) = []; 
                % -- Assigning and printing the output
                app.table_ports       = array2table(app.Port_UITable.Data, 'VariableNames', app.Port_UITable.ColumnName);
                fprintf("-> Port Deleted: N = " + string(app.num_of_ports) + " \n");
            else; fprintf("-> At least one port is required...\n");
            end
            % - Update data-structure
            update_myData(app);
        end

        % Button pushed function: Port_DeleteRowN_Button
        function Port_DeleteRowN_ButtonPushed(app, event)
            % - Removing one port from the UI tables (defined index)
            if app.num_of_ports ~= 1 
                prompt      = {'Enter the Port Number you want to delete:'};
                dlgtitle    = 'Delete Entry';
                dims        = [1 50];
                answer      = inputdlg(prompt,dlgtitle,dims);
                if ~isempty(answer) 
                    user_val = str2num(answer{1});
                    if user_val > app.num_of_ports || user_val < 1;
                        fprintf("-> Must enter a Port Number between 1 - " + string(app.num_of_ports) + "\n");
                    else
                        app.Port_UITable.Data(user_val,:) = []; 
                        app.num_of_ports = app.num_of_ports - 1;
                        for i = 1:app.num_of_ports; app.Port_UITable.Data(i,1) = {i}; end
                        % -- Assigning and printing the output
                        app.table_ports       = array2table(app.Port_UITable.Data, 'VariableNames', app.Port_UITable.ColumnName);
                        fprintf("-> Port #" + string(user_val) + " Deleted \n");
                    end
                end
            else; fprintf("-> At least one port is required...\n");
            end
            % - Update data-structure
            update_myData(app);
        end

        % Cell edit callback: Port_UITable
        function Port_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - General Validity check on the input values
            if indices(2) == 3; if newData < 0; newData = abs(newData); end; end
            if indices(2) == 3; if isnan(newData); newData = 0; end; end
            % 3 - Assigning the output
            app.Port_UITable.Data{indices(1),indices(2)} = newData;
            app.table_ports       = array2table(app.Port_UITable.Data, 'VariableNames', app.Port_UITable.ColumnName);
            % 4 - Printing the output
            if indices(2) == 2;     update_txt = sprintf("-> (Port %i) Flange = %s \n", indices(1), newData);
            elseif indices(2) == 3; update_txt = sprintf("-> (Port %i) Length = %.2f mm \n", indices(1), newData);
            elseif indices(2) == 4; update_txt = sprintf("-> (Port %i) Theta = %.2f deg. \n", indices(1), newData);
            elseif indices(2) == 5; update_txt = sprintf("-> (Port %i) Phi = %.2f deg. \n", indices(1), newData);
            elseif indices(2) == 6; update_txt = sprintf("-> (Port %i) Rotatable = %s \n", indices(1), newData);
            elseif indices(2) == 7; update_txt = sprintf("-> (Port %i) Tapped = %s \n", indices(1), newData);
            elseif indices(2) == 8; update_txt = sprintf("-> (Port %i) Usage = %s \n", indices(1), newData);
            elseif indices(2) == 9; update_txt = sprintf("-> (Port %i) Focal-Point = %s \n", indices(1), newData);
            end
            fprintf(update_txt);
            update_myData(app);
        end

        % Button pushed function: ViewModel_4Panel_Button
        function ViewModel_4Panel_ButtonPushed(app, event)
            view_final_chamber_4panel(app);
        end

        % Button pushed function: ViewModel_1Panel_Button
        function ViewModel_1Panel_ButtonPushed(app, event)
            view_final_chamber_1panel(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AppButtons_GridLayout = uigridlayout(app.GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.LOAD_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.LOAD_Button.ButtonPushedFcn = createCallbackFcn(app, @LOAD_ButtonPushed, true);
            app.LOAD_Button.Tooltip = {'Load in *.txt file'};
            app.LOAD_Button.Text = {'Load File...'; '(.txt)'};
            app.SAVEAS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.SAVEAS_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVEAS_ButtonPushed, true);
            app.SAVEAS_Button.Tooltip = {'Save the current design as a *.txt & .png file'};
            app.SAVEAS_Button.Text = {'Save As... '; '(.txt & .png)'};
            app.Image = uiimage(app.AppButtons_GridLayout);
            app.Image.Tooltip = {'Effortlessly design custom vacuum chambers with precision and speed in three-dimensions using our app.'};
            app.Image.ImageSource = fullfile(pathToMLAPP, '0_ReadMeImages', 'App_Icons', 'Icon_SnapVac3D_App.jpeg');
            app.ViewModel_1Panel_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.ViewModel_1Panel_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewModel_1Panel_ButtonPushed, true);
            app.ViewModel_1Panel_Button.Tooltip = {'View the current model (1-panel figure for isometric browsing)'};
            app.ViewModel_1Panel_Button.Text = {'View Model'; '(1-Panel)'};
            app.ViewModel_4Panel_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.ViewModel_4Panel_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewModel_4Panel_ButtonPushed, true);
            app.ViewModel_4Panel_Button.Tooltip = {'View the current model (4-panel figure showing isometric and plane cuts through the vacuum chamber)'};
            app.ViewModel_4Panel_Button.Text = {'View Model'; '(4-Panel)'};
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'SnapVac3D App';
            app.Chamber_Panel = uipanel(app.GridLayout);
            app.Chamber_Panel.Title = 'Main-Chamber Properties';
            app.Chamber_GridLayout = uigridlayout(app.Chamber_Panel);
            app.Chamber_GridLayout.Tooltip = {'Parameter Values Tab'};
            app.Chamber_UITable = uitable(app.Chamber_GridLayout);
            app.Chamber_UITable.ColumnName = {'Name'; 'Body'; 'Outer Diameter'; 'Wall'; 'Length'; 'Notes'};
            app.Chamber_UITable.RowName = {};
            app.Chamber_UITable.ColumnEditable = true;
            app.Chamber_UITable.CellEditCallback = createCallbackFcn(app, @Chamber_UITableCellEdit, true);
            app.Chamber_UITable.Tooltip = {'Table of information and the main chamber properties'};
            app.Port_Panel = uipanel(app.GridLayout);
            app.Port_Panel.Title = 'Port Properties';
            app.Port_GridLayout = uigridlayout(app.Port_Panel);
            app.Port_GridLayout.Tooltip = {'Parameter Values Tab'};
            app.Port_UITable = uitable(app.Port_GridLayout);
            app.Port_UITable.ColumnName = {'Port'; 'Flange'; 'Length'; 'Theta'; 'Phi'; 'Rotatable?'; 'Tapped?'; 'Usage'; 'Focal-Point'};
            app.Port_UITable.RowName = {};
            app.Port_UITable.ColumnEditable = true;
            app.Port_UITable.CellEditCallback = createCallbackFcn(app, @Port_UITableCellEdit, true);
            app.Port_UITable.Tooltip = {'Table of all the vacuum port properties'};
            app.Port_Add_Button = uibutton(app.Port_GridLayout, 'push');
            app.Port_Add_Button.ButtonPushedFcn = createCallbackFcn(app, @Port_Add_ButtonPushed, true);
            app.Port_Add_Button.Tooltip = {''};
            app.Port_Add_Button.Text = '+';
            app.Port_DeleteRowN_Button = uibutton(app.Port_GridLayout, 'push');
            app.Port_DeleteRowN_Button.ButtonPushedFcn = createCallbackFcn(app, @Port_DeleteRowN_ButtonPushed, true);
            app.Port_DeleteRowN_Button.Tooltip = {'Remove a user-defined port # '};
            app.Port_DeleteRowN_Button.Text = '-#';
            app.Port_Delete_Button = uibutton(app.Port_GridLayout, 'push');
            app.Port_Delete_Button.ButtonPushedFcn = createCallbackFcn(app, @Port_Delete_ButtonPushed, true);
            app.Port_Delete_Button.Tooltip = {'Remove the last port'};
            app.Port_Delete_Button.Text = '-';
            app.FocalPoint_Panel = uipanel(app.GridLayout);
            app.FocalPoint_Panel.Title = 'Focal-Point Properties';
            app.FocalPoint_GridLayout = uigridlayout(app.FocalPoint_Panel);
            app.FocalPoint_GridLayout.Tooltip = {'Parameter Values Tab'};
            app.FocalPoint_UITable = uitable(app.FocalPoint_GridLayout);
            app.FocalPoint_UITable.ColumnName = {'Name'; 'Color'; 'FPX'; 'FPY'; 'FPZ'};
            app.FocalPoint_UITable.RowName = {};
            app.FocalPoint_UITable.ColumnEditable = true;
            app.FocalPoint_UITable.CellEditCallback = createCallbackFcn(app, @FocalPoint_UITableCellEdit, true);
            app.FocalPoint_UITable.Tooltip = {'Table of all the focal-point properties'};
            app.FocalPoint_Add_Button = uibutton(app.FocalPoint_GridLayout, 'push');
            app.FocalPoint_Add_Button.ButtonPushedFcn = createCallbackFcn(app, @FocalPoint_Add_ButtonPushed, true);
            app.FocalPoint_Add_Button.Tooltip = {'Add a new focal-point'};
            app.FocalPoint_Add_Button.Text = '+';
            app.FocalPoint_Delete_Button = uibutton(app.FocalPoint_GridLayout, 'push');
            app.FocalPoint_Delete_Button.ButtonPushedFcn = createCallbackFcn(app, @FocalPoint_Delete_ButtonPushed, true);
            app.FocalPoint_Delete_Button.Tooltip = {'Remove a focal-point'};
            app.FocalPoint_Delete_Button.Text = '-';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.Tooltip = {'Definition of Axes in SnapVac3D'};
            app.Image2.ImageSource = fullfile(pathToMLAPP, '0_ReadMeImages', 'App_Icons', 'SnapVac3d-DefineAxes_main_01.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_SnapVac3D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end