classdef App_SnapVac3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        Plot_GridLayout            matlab.ui.container.GridLayout
        TransparencyType_DropDown  matlab.ui.control.DropDown
        RenderTypeLabel_2          matlab.ui.control.Label
        RenderType_DropDown        matlab.ui.control.DropDown
        RenderTypeLabel            matlab.ui.control.Label
        ViewModel_4Panel_Button    matlab.ui.control.Button
        ViewModel_1Panel_Button    matlab.ui.control.Button
        ViewLive_Button            matlab.ui.control.Button
        RenderColor_ColorPicker    matlab.ui.control.ColorPicker
        DRenderColorLabel          matlab.ui.control.Label
        DefinitionOfAxes_Image     matlab.ui.control.Image
        Port_Panel                 matlab.ui.container.Panel
        Port_GridLayout            matlab.ui.container.GridLayout
        Port_AutoSort_Button       matlab.ui.control.Button
        Port_Delete_Button         matlab.ui.control.Button
        Port_DeleteRowN_Button     matlab.ui.control.Button
        Port_Add_Button            matlab.ui.control.Button
        Port_UITable               matlab.ui.control.Table
        FocalPoint_Panel           matlab.ui.container.Panel
        FocalPoint_GridLayout      matlab.ui.container.GridLayout
        FocalPoint_Delete_Button   matlab.ui.control.Button
        FocalPoint_Add_Button      matlab.ui.control.Button
        FocalPoint_UITable         matlab.ui.control.Table
        Chamber_Panel              matlab.ui.container.Panel
        Chamber_GridLayout         matlab.ui.container.GridLayout
        Chamber_UITable            matlab.ui.control.Table
        AppTitle                   matlab.ui.control.Label
        AppButtons_GridLayout      matlab.ui.container.GridLayout
        Image                      matlab.ui.control.Image
        SAVEAS_Button              matlab.ui.control.Button
        LOAD_Button                matlab.ui.control.Button
        RESIZEUI_Button            matlab.ui.control.Button
        RESTART_Button             matlab.ui.control.Button
        CLOSEFIGS_Button           matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        myData                  % Data Structure containing all App variables
        table_chamber           % Table of chamber properties
        table_focalpoints       % Table of focal-points
        table_ports             % Table of port properties
        num_of_ports            % Number of ports
        num_of_focalpoints      % Number of focal-points
        color_of_focalpoints    % Colour of each focal-point
        plot_render_color       % Colour of the rendered image
        plot_render_type        % Type of rendering to use in the figure
        plot_transparency       % Value of the alpha transparency of all surfaces
        plot_axes_lims          % 3D Axes Limits of the figures
        num_of_points           % Total number of points for the surfaces
    end

    % Private methods available to the app only
    methods (Access = private)
        % Extract all the possible plot types
        function plot_render_types = extract_plot_render_types(app)
            plot_render_types = {...
                'Shiny',...
                'Metal',...
                'Dull',...
                'Faceted',...
                };
        end  

        % Extract all the possible transparency
        function plot_transparency_types = extract_plot_transparency_types(app)
            plot_transparency_types = {...
                '25%',...
                '50%',...
                '75%',...
                '100% (Opaque)',...
                };
        end  

        % Extract all the possible body types
        function body_types = extract_body_types(app)
            body_types = {...
                'Cylindrical',...
                'Spherical',...
                'Tetragonal',...
                'Capsule',...
                };
        end  

        % Extract all the possible flange sizes
        function flange_types = extract_flange_types(app)
            flange_types = {...
                'DN10CF',...
                'DN16CF',...
                'DN25CF',...
                'DN40CF',...
                'DN50CF',...
                'DN63CF',...
                'DN75CF',...
                'DN100CF',...
                'DN125CF',...
                'DN160CF',...
                'DN200CF',...
                'DN250CF',...
                'DN275CF',...
                'DN300CF',...
                'DN350CF',...
                'DN400CF',...
                };
        end  
        
        % Extract all the possible flange sizes
        function flange_properties = extract_flange_properties(app)
            flange_properties = struct();
            % Flange Thickness
            flange_properties.DN10CF.thickness  = 6;
            flange_properties.DN16CF.thickness  = 7;
            flange_properties.DN25CF.thickness  = 11.5;
            flange_properties.DN40CF.thickness  = 12.5;
            flange_properties.DN50CF.thickness  = 16;
            flange_properties.DN63CF.thickness  = 17;
            flange_properties.DN75CF.thickness  = 17.5;
            flange_properties.DN100CF.thickness = 19.5;
            flange_properties.DN125CF.thickness = 21;
            flange_properties.DN160CF.thickness = 21;
            flange_properties.DN200CF.thickness = 24;
            flange_properties.DN250CF.thickness = 24;
            flange_properties.DN275CF.thickness = 28;
            flange_properties.DN300CF.thickness = 28;
            flange_properties.DN350CF.thickness = 28;
            flange_properties.DN400CF.thickness = 28;
            % Flange Outer Diameter
            flange_properties.DN10CF.od     = 25;
            flange_properties.DN16CF.od     = 33.8;
            flange_properties.DN25CF.od     = 54;
            flange_properties.DN40CF.od     = 69.9;
            flange_properties.DN50CF.od     = 85.7;
            flange_properties.DN63CF.od     = 114.3;
            flange_properties.DN75CF.od     = 117.4;
            flange_properties.DN100CF.od    = 152.4;
            flange_properties.DN125CF.od    = 171.5;
            flange_properties.DN160CF.od    = 203.2;
            flange_properties.DN200CF.od    = 254;
            flange_properties.DN250CF.od    = 304.8;
            flange_properties.DN275CF.od    = 336.6;
            flange_properties.DN300CF.od    = 368.3;
            flange_properties.DN350CF.od    = 419.1;
            flange_properties.DN400CF.od    = 469.9;
        end  

        % Extract results for rotatable or Threaded flanges
        function binary_types = extract_binary_types(app)
            binary_types = {...
                'yes',...
                'no',...
                };
        end  
        
        % Extract results for rotatable or Threaded flanges
        function focal_types = extract_focal_types(app)
            Alphabet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
            for i = 1:app.num_of_focalpoints
                focal_types{i} = Alphabet(i);
            end
        end  

        % Update the myData structure
        function update_myData(app)
            app.myData.TimeStamp            = datetime;
            app.myData.FileName             = string(app.table_chamber.Name{1});
            app.myData.Notes                = string(app.table_chamber.Notes{1});
            app.myData.table_chamber        = app.table_chamber;
            app.myData.table_focalpoints    = app.table_focalpoints;
            app.myData.table_ports          = app.table_ports;
            app.myData.num_of_focalpoints   = app.num_of_focalpoints;
            app.myData.num_of_ports         = app.num_of_ports;
            app.myData.color_of_focalpoints = app.color_of_focalpoints;
            app.myData.plot_render_color    = app.plot_render_color;
            app.myData.plot_render_type     = app.plot_render_type;
            app.myData.plot_transparency    = app.plot_transparency;
            app.myData.num_of_points        = app.num_of_points;
        end  

        % Extract main-chamber coordinates
        function main_surfs = extract_main_chamber_coords(app, type, x0, y0, z0, diameter, height, wall_thickness, num_points)
            x0 = -x0; y0 = -y0; z0 = -z0;
            % 1 - Extract & plot the main body
            if strcmpi(type, "Cylindrical")
                % -- Extracting coordinates for cylindrical tube
                theta           = linspace(0, 2*pi, num_points);
                z               = linspace(-height/2, height/2, num_points);
                inner_radius    = 0.5*diameter - 0.5*wall_thickness;
                outer_radius    = 0.5*diameter + 0.5*wall_thickness;
                [Theta, z_cyl]  = meshgrid(theta, z);
                % --- Outer cylinder coordinates
                x_cyl_outer     = outer_radius.*cos(Theta) - x0;
                y_cyl_outer     = outer_radius.*sin(Theta) - y0;
                z_cyl_outer     = z_cyl - z0;
                % --- Inner cylinder coordinates
                x_cyl_inner     = inner_radius.*cos(Theta) - x0;
                y_cyl_inner     = inner_radius.*sin(Theta) - y0;
                z_cyl_inner     = z_cyl - z0;
                % -- Extracting coordinates for top and bottom caps
                theta_cap           = linspace(0, 2*pi, num_points);
                [Theta_cap, R_cap]  = meshgrid(theta_cap, linspace(0, 0.99*outer_radius, num_points));
                % --- Top-cap outer coordinates
                x_cap_top_outer     = R_cap.*cos(Theta_cap) - x0;
                y_cap_top_outer     = R_cap.*sin(Theta_cap) - y0;
                z_cap_top_outer     = height/2 .* ones(size(x_cap_top_outer)) - z0 + 0.5*wall_thickness;
                % --- Top-cap inner coordinates
                x_cap_top_inner     = R_cap.*cos(Theta_cap) - x0;
                y_cap_top_inner     = R_cap.*sin(Theta_cap) - y0;
                z_cap_top_inner     = height/2 .* ones(size(x_cap_top_inner)) - z0 - 0.5*wall_thickness;
                % --- Bottom-cap outer coordinates
                x_cap_bot_outer     = R_cap.*cos(Theta_cap) - x0;
                y_cap_bot_outer     = R_cap.*sin(Theta_cap) - y0;
                z_cap_bot_outer     = -height/2 .* ones(size(x_cap_bot_outer)) - z0 + 0.5*wall_thickness;
                % --- Bottom-cap inner coordinates
                x_cap_bot_inner     = R_cap.*cos(Theta_cap) - x0;
                y_cap_bot_inner     = R_cap.*sin(Theta_cap) - y0;
                z_cap_bot_inner     = -height/2 .* ones(size(x_cap_bot_inner)) - z0 - 0.5*wall_thickness;
                % -- Output the data
                main_surfs = {};
                main_surfs{1}.XData = x_cyl_outer;
                main_surfs{1}.YData = y_cyl_outer;
                main_surfs{1}.ZData = z_cyl_outer;
                main_surfs{2}.XData = x_cyl_inner;
                main_surfs{2}.YData = y_cyl_inner;
                main_surfs{2}.ZData = z_cyl_inner;
                main_surfs{3}.XData = [x_cap_top_outer; flipud(x_cap_top_inner)];
                main_surfs{3}.YData = [y_cap_top_outer; flipud(y_cap_top_inner)];
                main_surfs{3}.ZData = [z_cap_top_outer; flipud(z_cap_top_inner)];
                main_surfs{4}.XData = [x_cap_bot_outer; flipud(x_cap_bot_inner)];
                main_surfs{4}.YData = [y_cap_bot_outer; flipud(y_cap_bot_inner)];
                main_surfs{4}.ZData = [z_cap_bot_outer; flipud(z_cap_bot_inner)];

            elseif strcmpi(type, "Spherical")
                % -- Extracting coordinates for a sphere
                theta           = linspace(0, 2*pi, num_points);
                phi             = linspace(0, pi, num_points);
                [Theta, Phi]    = meshgrid(theta, phi);
                % -- Outer spherical coordinates
                R = (0.5.*diameter + 0.5*wall_thickness);
                x_sphere_outer   = R .* sin(Phi) .* cos(Theta) - x0;
                y_sphere_outer   = R .* sin(Phi) .* sin(Theta) - y0;
                z_sphere_outer   = R .* cos(Phi) - z0;
                % -- Inner spherical coordinates
                R = (0.5.*diameter - 0.5*wall_thickness);
                x_sphere_inner   = R .* sin(Phi) .* cos(Theta) - x0;
                y_sphere_inner   = R .* sin(Phi) .* sin(Theta) - y0;
                z_sphere_inner   = R .* cos(Phi) - z0;
                % -- Output the data
                main_surfs = {};
                main_surfs{1}.XData = x_sphere_outer;
                main_surfs{1}.YData = y_sphere_outer;
                main_surfs{1}.ZData = z_sphere_outer;
                main_surfs{2}.XData = x_sphere_inner;
                main_surfs{2}.YData = y_sphere_inner;
                main_surfs{2}.ZData = z_sphere_inner;

            elseif strcmpi(type, "Tetragonal")
                % -- Calculate half of the edge length to shift the cube's center to the origin
                halfEdge_inner    = (0.5.*diameter - 0.5*wall_thickness);
                halfHeight_inner  = (0.5.*height - 0.5*wall_thickness);
                halfEdge_outer    = (0.5.*diameter + 0.5*wall_thickness);
                halfHeight_outer  = (0.5.*height + 0.5*wall_thickness);
                % -- Create vectors for the cube's vertices, centered at the origin
                % -- Inner faces
                x_inner           = linspace(-halfEdge_inner, halfEdge_inner, num_points) - x0;
                y_inner           = linspace(-halfEdge_inner, halfEdge_inner, num_points) - y0;
                z_inner           = linspace(-halfHeight_inner, halfHeight_inner, num_points) - z0;
                % -- Outer faces
                x_outer           = linspace(-halfEdge_outer, halfEdge_outer, num_points) - x0;
                y_outer           = linspace(-halfEdge_outer, halfEdge_outer, num_points) - y0;
                z_outer           = linspace(-halfHeight_outer, halfHeight_outer, num_points) - z0;
                % -- Create meshgrids for the six faces of the cube (inner)
                % --- Bottom and top faces
                [X1_inner, Y1_inner]    = meshgrid(x_inner, y_inner); Z1_inner = halfHeight_inner * ones(size(X1_inner));
                % --- Front and back faces
                [Z2_inner, X2_inner]    = meshgrid(z_inner, x_inner); Y2_inner = halfEdge_inner * ones(size(X2_inner));
                % --- Left and right faces
                [Z3_inner, Y3_inner]    = meshgrid(z_inner, y_inner); X3_inner = halfEdge_inner * ones(size(Y3_inner)); 
                % -- Create meshgrids for the six faces of the cube (outer)
                % --- Bottom and top faces
                [X1_outer, Y1_outer]    = meshgrid(x_outer, y_outer); Z1_outer = halfHeight_outer * ones(size(X1_outer));
                % --- Front and back faces
                [Z2_outer, X2_outer]    = meshgrid(z_outer, x_outer); Y2_outer = halfEdge_outer * ones(size(X2_outer));
                % --- Left and right faces
                [Z3_outer, Y3_outer]    = meshgrid(z_outer, y_outer); X3_outer = halfEdge_outer * ones(size(Y3_outer));
                % -- Output the data
                main_surfs = {};
                main_surfs{1}.XData = X1_inner;
                main_surfs{1}.YData = Y1_inner;
                main_surfs{1}.ZData = -Z1_inner;
                main_surfs{2}.XData = X1_inner;
                main_surfs{2}.YData = Y1_inner;
                main_surfs{2}.ZData = Z1_inner;
                main_surfs{3}.XData = X2_inner;
                main_surfs{3}.YData = -Y2_inner;
                main_surfs{3}.ZData = Z2_inner;
                main_surfs{4}.XData = X2_inner;
                main_surfs{4}.YData = Y2_inner;
                main_surfs{4}.ZData = Z2_inner;
                main_surfs{5}.XData = -X3_inner;
                main_surfs{5}.YData = Y3_inner;
                main_surfs{5}.ZData = Z3_inner;
                main_surfs{6}.XData = X3_inner;
                main_surfs{6}.YData = Y3_inner;
                main_surfs{6}.ZData = Z3_inner;
                main_surfs{7}.XData = X1_outer;
                main_surfs{7}.YData = Y1_outer;
                main_surfs{7}.ZData = -Z1_outer;
                main_surfs{8}.XData = X1_outer;
                main_surfs{8}.YData = Y1_outer;
                main_surfs{8}.ZData = Z1_outer;
                main_surfs{9}.XData = X2_outer;
                main_surfs{9}.YData = -Y2_outer;
                main_surfs{9}.ZData = Z2_outer;
                main_surfs{10}.XData = X2_outer;
                main_surfs{10}.YData = Y2_outer;
                main_surfs{10}.ZData = Z2_outer;
                main_surfs{11}.XData = -X3_outer;
                main_surfs{11}.YData = Y3_outer;
                main_surfs{11}.ZData = Z3_outer;
                main_surfs{12}.XData = X3_outer;
                main_surfs{12}.YData = Y3_outer;
                main_surfs{12}.ZData = Z3_outer;

            elseif strcmpi(type, "Capsule")
                % -- Extracting coordinates for cylindrical tube
                theta           = linspace(0, 2*pi, num_points);
                z               = linspace(-height/2, height/2, num_points);
                inner_radius    = 0.5*diameter - 0.5*wall_thickness;
                outer_radius    = 0.5*diameter + 0.5*wall_thickness;
                [Theta, z_cyl]  = meshgrid(theta, z);
                % --- Outer cylinder coordinates
                x_cyl_outer     = outer_radius.*cos(Theta) - x0;
                y_cyl_outer     = outer_radius.*sin(Theta) - y0;
                z_cyl_outer     = z_cyl - z0;
                % --- Inner cylinder coordinates
                x_cyl_inner     = inner_radius.*cos(Theta) - x0;
                y_cyl_inner     = inner_radius.*sin(Theta) - y0;
                z_cyl_inner     = z_cyl - z0;
                % -- Extracting coordinates for top and bottom half-spheres
                phi                 = linspace(0, pi/2, num_points);
                [Theta_cap, Phi]    = meshgrid(theta, phi);
                % -- Top half-sphere
                % --- Inner sphere
                R = (0.5.*diameter - 0.5*wall_thickness);
                x_cap_top_inner       = R * sin(Phi) .* cos(Theta_cap) - x0;
                y_cap_top_inner       = R * sin(Phi) .* sin(Theta_cap) - y0;
                z_cap_top_inner       = R * cos(Phi) + height/2 - z0; 
                % --- Outer sphere
                R = (0.5.*diameter + 0.5*wall_thickness);
                x_cap_top_outer       = R * sin(Phi) .* cos(Theta_cap) - x0;
                y_cap_top_outer       = R * sin(Phi) .* sin(Theta_cap) - y0;
                z_cap_top_outer       = R * cos(Phi) + height/2 - z0; 
                % --- Inner sphere
                R = (0.5.*diameter - 0.5*wall_thickness);
                x_cap_bot_inner       = R * sin(Phi) .* cos(Theta_cap) - x0;
                y_cap_bot_inner       = R * sin(Phi) .* sin(Theta_cap) - y0;
                z_cap_bot_inner       = -R * cos(Phi) - height/2 - z0; 
                % --- Outer sphere
                R = (0.5.*diameter + 0.5*wall_thickness);
                x_cap_bot_outer       = R * sin(Phi) .* cos(Theta_cap) - x0;
                y_cap_bot_outer       = R * sin(Phi) .* sin(Theta_cap) - y0;
                z_cap_bot_outer       = -R * cos(Phi) - height/2 - z0; 
                % -- Output the data
                main_surfs = {};
                main_surfs{1}.XData = x_cap_top_outer;
                main_surfs{1}.YData = y_cap_top_outer;
                main_surfs{1}.ZData = z_cap_top_outer;
                main_surfs{2}.XData = x_cap_top_inner;
                main_surfs{2}.YData = y_cap_top_inner;
                main_surfs{2}.ZData = z_cap_top_inner;
                main_surfs{3}.XData = x_cyl_outer;
                main_surfs{3}.YData = y_cyl_outer;
                main_surfs{3}.ZData = z_cyl_outer;
                main_surfs{4}.XData = x_cyl_inner;
                main_surfs{4}.YData = y_cyl_inner;
                main_surfs{4}.ZData = z_cyl_inner;
                main_surfs{5}.XData = x_cap_bot_inner;
                main_surfs{5}.YData = y_cap_bot_inner;
                main_surfs{5}.ZData = z_cap_bot_inner;
                main_surfs{6}.XData = x_cap_bot_outer;
                main_surfs{6}.YData = y_cap_bot_outer;
                main_surfs{6}.ZData = z_cap_bot_outer;
            end
        end

        % Extract port & flange coordinates
        function [tube_surfs, flange_surfs] = extract_port_coords(app, x0, y0, z0, theta, phi, flange, length, wall_thickness, num_points)
            % 1 - Extract the flange diameter
            flange_diameter = regexp(flange, '\d+', 'match');
            flange_diameter = str2double(flange_diameter{1});
            % 2 - Exract the rotation matrix
            theta   = -deg2rad(theta);
            phi     = deg2rad(phi);
            % -- Rotation matrix around z-axis by phi
            Rz      = [cos(phi) -sin(phi) 0;
                        sin(phi) cos(phi) 0;
                        0 0 1];
            % -- Rotation matrix around the y-axis by theta
            Ry      = [cos(theta) 0 sin(theta);
                        0 1 0;
                        -sin(theta) 0 cos(theta)];
            % -- Combined rotation matrix (first Ry, then Rz)
            R = Rz * Ry;
            
            % 3 - Extract the port tube coordinates
            % -- Create cylinder and scale to correct size
            start_point     = 0.25*(0.5*app.table_chamber{1,3}{1});
            start_point     = 0;
            itheta          = linspace(0, 2*pi, num_points);
            x               = linspace(start_point, length, num_points);
            inner_radius    = 0.5*flange_diameter - 0.5*wall_thickness;
            outer_radius    = 0.5*flange_diameter + 0.5*wall_thickness;
            % --- Outer cylinder coordinates
            [iTheta, x_outer]   = meshgrid(itheta, x);
            y_outer             = outer_radius.*cos(iTheta);
            z_outer             = outer_radius.*sin(iTheta);
            % --- Inner cylinder coordinates
            x_inner             = x_outer;
            y_inner             = inner_radius.*cos(iTheta);
            z_inner             = inner_radius.*sin(iTheta);
            % -- Apply the rotation matrix 
            % --- Outer cylinder
            cylinder_coords_outer     = [x_outer(:) y_outer(:) z_outer(:)];
            rotated_coords_outer      = (R * cylinder_coords_outer')';
            % --- Inner cylinder
            cylinder_coords_inner     = [x_inner(:) y_inner(:) z_inner(:)];
            rotated_coords_inner      = (R * cylinder_coords_inner')';
            % -- Reshape the rotated coordinates back into 2D arrays
            % --- Outer cylinder
            x_rot_outer         = reshape(rotated_coords_outer(:,1), size(x_outer));
            y_rot_outer         = reshape(rotated_coords_outer(:,2), size(y_outer));
            z_rot_outer         = reshape(rotated_coords_outer(:,3), size(z_outer));
            % --- Inner cylinder
            x_rot_inner         = reshape(rotated_coords_inner(:,1), size(x_inner));
            y_rot_inner         = reshape(rotated_coords_inner(:,2), size(y_inner));
            z_rot_inner         = reshape(rotated_coords_inner(:,3), size(z_inner));
            % -- Shift the coordinates to a focal point
            % --- Outer cylinder
            x_tube_outer      = x_rot_outer + x0;
            y_tube_outer      = y_rot_outer + y0;
            z_tube_outer      = z_rot_outer + z0;
            % --- Inner cylinder
            x_tube_inner      = x_rot_inner + x0;
            y_tube_inner      = y_rot_inner + y0;
            z_tube_inner      = z_rot_inner + z0;
            % -- Output the data
            tube_surfs = {};
            tube_surfs{1}.XData = x_tube_outer;
            tube_surfs{1}.YData = y_tube_outer;
            tube_surfs{1}.ZData = z_tube_outer;
            tube_surfs{2}.XData = x_tube_inner;
            tube_surfs{2}.YData = y_tube_inner;
            tube_surfs{2}.ZData = z_tube_inner;

            % 4 - Extract the flange coordinates
            % -- Defining flange parameters
            flange_properties   = extract_flange_properties(app);
            flange_thickness    = flange_properties.(flange).thickness;
            flange_od           = flange_properties.(flange).od;
            itheta              = linspace(0, 2*pi, num_points);
            x                   = linspace(-flange_thickness, 0, 5);
            inner_radius        = 0.5*flange_diameter - 0.5*wall_thickness;
            outer_radius        = 0.5*flange_od;
            % -- Outer cylinder coordinates
            [iTheta, x_outer]   = meshgrid(itheta, x);
            y_outer             = outer_radius.*cos(iTheta);
            z_outer             = outer_radius.*sin(iTheta);
            % -- Inner cylinder coordinates
            x_inner             = x_outer;
            y_inner             = inner_radius.*cos(iTheta);
            z_inner             = inner_radius.*sin(iTheta);
            % -- Create hollow circular caps
            [Theta_cap, R_cap] = meshgrid(itheta, linspace(inner_radius, outer_radius, 5));
            % --- Top cap
            y_cap_top           = R_cap .* sin(Theta_cap);
            z_cap_top           = R_cap .* cos(Theta_cap); 
            x_cap_top           = 0 .* ones(size(z_cap_top)); 
            % --- Bottom cap
            y_cap_bottom        = R_cap .* sin(Theta_cap);
            z_cap_bottom        = R_cap .* cos(Theta_cap);
            x_cap_bottom        = -flange_thickness .* ones(size(z_cap_bottom));
            % -- Apply the rotation matrix 
            % --- Outer cylinder
            cylinder_coords_outer     = [x_outer(:) y_outer(:) z_outer(:)];
            rotated_coords_outer      = (R * cylinder_coords_outer')';
            % --- Inner cylinder
            cylinder_coords_inner     = [x_inner(:) y_inner(:) z_inner(:)];
            rotated_coords_inner      = (R * cylinder_coords_inner')';
            % --- Top cap
            cylinder_coords_cap_top     = [x_cap_top(:) y_cap_top(:) z_cap_top(:)];
            rotated_coords_cap_top      = (R * cylinder_coords_cap_top')';
            % --- Bottom cap
            cylinder_coords_cap_bottom     = [x_cap_bottom(:) y_cap_bottom(:) z_cap_bottom(:)];
            rotated_coords_cap_bottom      = (R * cylinder_coords_cap_bottom')';
            % -- Reshape the rotated coordinates back into 2D arrays
            % --- Outer cylinder
            x_rot_outer         = reshape(rotated_coords_outer(:,1), size(x_outer));
            y_rot_outer         = reshape(rotated_coords_outer(:,2), size(y_outer));
            z_rot_outer         = reshape(rotated_coords_outer(:,3), size(z_outer));
            % --- Inner cylinder
            x_rot_inner         = reshape(rotated_coords_inner(:,1), size(x_inner));
            y_rot_inner         = reshape(rotated_coords_inner(:,2), size(y_inner));
            z_rot_inner         = reshape(rotated_coords_inner(:,3), size(z_inner));
            % --- Top cap
            x_rot_cap_top       = reshape(rotated_coords_cap_top(:,1), size(x_cap_top));
            y_rot_cap_top       = reshape(rotated_coords_cap_top(:,2), size(y_cap_top));
            z_rot_cap_top       = reshape(rotated_coords_cap_top(:,3), size(z_cap_top));
            % --- Bottom cap
            x_rot_cap_bottom    = reshape(rotated_coords_cap_bottom(:,1), size(x_cap_bottom));
            y_rot_cap_bottom    = reshape(rotated_coords_cap_bottom(:,2), size(y_cap_bottom));
            z_rot_cap_bottom    = reshape(rotated_coords_cap_bottom(:,3), size(z_cap_bottom));
            % -- Displace flange to the end of the port
            x_shift     = length.*sin(pi/2-theta).*cos(phi);
            y_shift     = length.*sin(pi/2-theta).*sin(phi);
            z_shift     = length.*cos(pi/2-theta);
            % -- Final flange coordinates
            % --- Outer cylinder
            x_flange_outer      = x_rot_outer + x0 + x_shift;
            y_flange_outer      = y_rot_outer + y0 + y_shift;
            z_flange_outer      = z_rot_outer + z0 - z_shift;
            % --- Inner cylinder
            x_flange_inner      = x_rot_inner + x0 + x_shift;
            y_flange_inner      = y_rot_inner + y0 + y_shift;
            z_flange_inner      = z_rot_inner + z0 - z_shift;
            % --- Top cap
            x_flange_cap_top      = x_rot_cap_top + x0 + x_shift;
            y_flange_cap_top      = y_rot_cap_top + y0 + y_shift;
            z_flange_cap_top      = z_rot_cap_top + z0 - z_shift;
            % --- Bottom cap
            x_flange_cap_bottom      = x_rot_cap_bottom + x0 + x_shift;
            y_flange_cap_bottom      = y_rot_cap_bottom + y0 + y_shift;
            z_flange_cap_bottom      = z_rot_cap_bottom + z0 - z_shift;
            % -- Output the data
            flange_surfs = {};
            flange_surfs{1}.XData = x_flange_outer;
            flange_surfs{1}.YData = y_flange_outer;
            flange_surfs{1}.ZData = z_flange_outer;
            flange_surfs{2}.XData = x_flange_inner;
            flange_surfs{2}.YData = y_flange_inner;
            flange_surfs{2}.ZData = z_flange_inner;
            flange_surfs{3}.XData = x_flange_cap_top;
            flange_surfs{3}.YData = y_flange_cap_top;
            flange_surfs{3}.ZData = z_flange_cap_top;
            flange_surfs{4}.XData = x_flange_cap_bottom;
            flange_surfs{4}.YData = y_flange_cap_bottom;
            flange_surfs{4}.ZData = z_flange_cap_bottom;
        end

        % Plot the final model of the vacuum chamber
        function extract_and_plot_final_model(app)
            % 1 - Extracting all the input arguments
            % 1.1 - Chamber Properties
            FileName            = app.myData.table_chamber{1,1}{1};
            MainBody            = app.myData.table_chamber{1,2}{1};
            OuterDiameter       = app.myData.table_chamber{1,3}{1};
            WallThickness       = app.myData.table_chamber{1,4}{1};
            LengthMainBody      = app.myData.table_chamber{1,5}{1};
            Notes               = app.myData.table_chamber{1,6}{1};
            % 1.2 - Focal-Point Properties
            FP = {};
            for i = 1:app.num_of_focalpoints
                FP{i} = [app.myData.table_focalpoints{i,3}{1}, app.myData.table_focalpoints{i,4}{1}, app.myData.table_focalpoints{i,5}{1}];
            end
            FP_cols             = app.color_of_focalpoints;
            % 1.3 - Port Properties
            Port                = cell2mat(app.myData.table_ports{:,1});
            Flange              = app.myData.table_ports{:,2};
            Length              = cell2mat(app.myData.table_ports{:,3});
            Theta               = cell2mat(app.myData.table_ports{:,4});
            Phi                 = cell2mat(app.myData.table_ports{:,5});
            Rotatable           = app.myData.table_ports{:,6};
            Threaded              = app.myData.table_ports{:,7};
            Usage               = app.myData.table_ports{:,8};
            FPP                 = app.myData.table_ports{:,9};
            % 1.4 - Sort in terms of ascending order of focal-points
            [FPP, sorted_order] = sort(FPP);
            Port        = Port(sorted_order);
            Flange      = Flange(sorted_order);
            Length      = Length(sorted_order);
            Theta       = Theta(sorted_order);
            Phi         = Phi(sorted_order);
            Rotatable   = Rotatable(sorted_order);
            Threaded      = Threaded(sorted_order);
            Usage       = Usage(sorted_order);

            % 2 - Extract main-chamber coordinates
            main_surfs = extract_main_chamber_coords(app, MainBody, FP{1}(1), FP{1}(2), FP{1}(3), OuterDiameter, LengthMainBody, WallThickness, app.num_of_points);
            
            % 3 - Extract all port coordinates
            fp_idx = []; tube_surfs = {}; flange_surfs = {};
            focal_types = extract_focal_types(app);
            for i = 1:app.num_of_ports
                [~, fp_idx(i)]             = ismember(FPP{i}, focal_types);
                [tube_surfs{i}, flange_surfs{i}]    = extract_port_coords(app, FP{fp_idx(i)}(1), FP{fp_idx(i)}(2), FP{fp_idx(i)}(3), Theta(i), Phi(i), Flange{i}, Length(i), WallThickness, app.num_of_points);
            end

            % 4 - Extracting port variables
            % 4.1 -- Extracting straight line through port axis 
            XLine_Port = {}; 
            YLine_Port = {}; 
            ZLine_Port = {};
            for i = 1:app.num_of_ports
                % -- Extracting line as Spherical coordinates
                r = linspace(0, 1.1 * Length(i), app.num_of_points);
                theta = deg2rad(Theta(i)) * ones(size(r));
                phi = deg2rad(Phi(i)) * ones(size(r));
                % -- Conversion to Cartesian coordinates
                idx = fp_idx(i); % Store index to avoid repetitive function calls
                if length(XLine_Port) < idx
                    XLine_Port{idx} = {}; 
                    YLine_Port{idx} = {}; 
                    ZLine_Port{idx} = {};
                end
                XLine_Port{idx}{end+1} = r .* sin(pi/2 - theta) .* cos(phi) + FP{idx}(1);
                YLine_Port{idx}{end+1} = r .* sin(pi/2 - theta) .* sin(phi) + FP{idx}(2);
                ZLine_Port{idx}{end+1} = r .* cos(pi/2 - theta) + FP{idx}(3);
            end
            
            % 4.2 -- Finding the parent bodies
            % All parent bodies of first focal point is the main body
            if isempty(XLine_Port{1});  Parent_Idx = 0;
            else;                       Parent_Idx = zeros(1, length(XLine_Port{1}));
            end
            % Finding the parent bodies of all other ports
            if size(XLine_Port, 2) > 1
                for i = 2:size(XLine_Port, 2)
                    for j = 1:length(XLine_Port{i})
                        % --- Extracting current port line
                        xdat_curr = XLine_Port{i}{j};
                        ydat_curr = YLine_Port{i}{j};
                        zdat_curr = ZLine_Port{i}{j};
                        % --- Extracting port lines from all previous focal points
                        idx = 0;
                        for ii = 1:(i-1)
                            for k = 1:length(XLine_Port{ii})
                                idx = idx+1;
                                xdat_prev = XLine_Port{ii}{k};
                                ydat_prev = YLine_Port{ii}{k};
                                zdat_prev = ZLine_Port{ii}{k};
                                % ---- Finding the point on the main-body nearest the line
                                distance_matrix = sqrt((xdat_curr - xdat_prev).^2 + ...
                                                 (ydat_curr - ydat_prev).^2 + ...
                                                 (zdat_curr - zdat_prev).^2);
                                % ---- Find the minimum value in the matrix
                                port_index(idx)   = idx; 
                                minVal(idx)       = min(distance_matrix(:)); 
                            end
                        end
                        % --- Extracting the parent port index
                        [~, portIdx] = min(minVal);
                        if ~isempty(portIdx); Parent_Idx(end+1) = portIdx; end
                    end
                end
            end
            % Force all ports with focal points inside the main-chamber to have the main-chamber as the parent
            for i = 1:app.num_of_ports
                idx = fp_idx(i); % Store index to avoid repetitive function calls
                x_fp = FP{idx}(1); y_fp = FP{idx}(2); z_fp = FP{idx}(3);
                radi_threshold      = 0.5*OuterDiameter;
                length_threshold    = 0.5*LengthMainBody;
                if strcmpi(MainBody, "Cylindrical")
                    xy_distances        = sqrt(x_fp.^2 + y_fp.^2);
                    z_distances         = sqrt(z_fp.^2);
                    if (xy_distances < radi_threshold) && (z_distances < length_threshold); Parent_Idx(i) = 0; end
                elseif strcmpi(MainBody, "Spherical")
                    xyz_distances        = sqrt(x_fp.^2 + y_fp.^2 + z_fp.^2);
                    if (xyz_distances < radi_threshold); Parent_Idx(i) = 0; end
                elseif strcmpi(MainBody, "Tetragonal")
                    x_distances   = sqrt((x_fp).^2);
                    y_distances   = sqrt((y_fp).^2);
                    z_distances   = sqrt((z_fp).^2);
                    if (x_distances < radi_threshold) && ((y_distances < radi_threshold)) && ((z_distances < length_threshold)); Parent_Idx(i) = 0; end
                elseif strcmpi(MainBody, "Capsule")
                    xy_distances        = sqrt(x_fp.^2 + y_fp.^2);
                    z_distances         = sqrt(z_fp.^2);
                    if (xy_distances < radi_threshold) && (z_distances < length_threshold); Parent_Idx(i) = 0; end
                    xyz_distances       = sqrt(x_fp.^2 + y_fp.^2 + (z_fp - 0.5*LengthMainBody).^2);
                    if (xyz_distances < radi_threshold); Parent_Idx(i) = 0; end
                    xyz_distances       = sqrt(x_fp.^2 + y_fp.^2 + (z_fp + 0.5*LengthMainBody).^2);
                    if (xyz_distances < radi_threshold); Parent_Idx(i) = 0; end
                end
            end
            % 4.3 -- Extracting Port variables to arrays
            Parent_Port     = Parent_Idx;
            XLine_Port      = [XLine_Port{:}];
            YLine_Port      = [YLine_Port{:}];
            ZLine_Port      = [ZLine_Port{:}];

            % 5 - Point-Of-Intersection Removal
            for i = 1:app.num_of_ports
                % - For all ports whose parent is the Main-Chamber
                if Parent_Port(i) == 0
                    % 5.1 - MAIN-CHAMBER INTERSECTIONS
                    rowIdx = []; colIdx = []; distance = []; 
                    x2_flat = []; y2_flat = []; z2_flat = []; 
                    % -- Extracting straight line through port axis
                    x2_flat        = XLine_Port{i};
                    y2_flat        = YLine_Port{i};
                    z2_flat        = ZLine_Port{i};
                    for j = 1:size(main_surfs,2)
                        distance_matrix = []; x1_flat = []; y1_flat = []; z1_flat = []; 
                        % --- Flatten the coordinates for vectorized distance calculations
                        x1_flat     = main_surfs{j}.XData(:);
                        y1_flat     = main_surfs{j}.YData(:);
                        z1_flat     = main_surfs{j}.ZData(:);
                        % --- Finding the point on the main-body nearest the line
                        distance_matrix     = sqrt((x1_flat - x2_flat).^2 + ...
                                                (y1_flat - y2_flat).^2 + ...
                                                (z1_flat - z2_flat).^2);
                        % --- Find the minimum value in the matrix and its linear index 
                        [~, minIdx] = min(distance_matrix(:)); 
                        [rowIdx(j), colIdx(j)] = ind2sub(size(distance_matrix), minIdx);
                        distance(j)     = distance_matrix(rowIdx(j), colIdx(j));
                    end
                    % -- Finding the true minimum distance from all main-body surfaces
                    [~,iindx] = min(abs(distance(:)));
                    % -- Defining point-of-intercept on the main-body
                    xc      = x2_flat(colIdx(iindx));
                    yc      = y2_flat(colIdx(iindx));
                    zc      = z2_flat(colIdx(iindx));
                    % -- Extracting flange diameter
                    flange_diameter = regexp(Flange{i}, '\d+', 'match');
                    radi_threshold  = 0.45*str2double(flange_diameter{1});
                    % -- Executing removal of intersection points for all main chamber surfaces
                    for j = 1:size(main_surfs,2)
                        distance_matrix = []; x1_flat = []; y1_flat = []; z1_flat = []; 
                        % --- Flatten the coordinates for vectorized distance calculations
                        x1_flat         = main_surfs{j}.XData(:);
                        y1_flat         = main_surfs{j}.YData(:);
                        z1_flat         = main_surfs{j}.ZData(:);
                        % --- Calculate distance to the sphere center
                        distance_matrix = sqrt((x1_flat - xc).^2 +...
                                            (y1_flat - yc).^2 +...
                                            (z1_flat - zc).^2);
                        % --- Find points inside the sphere at intersection point
                        inside_sphere   = distance_matrix <= radi_threshold;
                        x_out           = x1_flat(inside_sphere);
                        y_out           = y1_flat(inside_sphere);
                        z_out           = z1_flat(inside_sphere);
                        % --- Plot the intersecting points
                        main_surfs{j}.XData(any(inside_sphere, 2)) = NaN;
                        main_surfs{j}.YData(any(inside_sphere, 2)) = NaN;
                        main_surfs{j}.ZData(any(inside_sphere, 2)) = NaN;
                        % --- DEBUG PLOT: Plotting all deleted points
                        % plot3(x_out, y_out, z_out, 'r.', 'MarkerSize', 8);
                    end
                    % % -- DEBUG PLOT: Plotting port lines & centre of intersection
                    % plot3(x2_flat, y2_flat, z2_flat, 'r:', 'linewidth', 2);
                    % plot3(xc, yc, zc, 'ko', 'linewidth', 3, 'markersize', 15);

                    % -- Executing removal of intersection points for all port surfaces
                    for j = 1:app.num_of_ports
                        for k = 1:size(tube_surfs{j},2)
                            distance_matrix = []; x1_flat = []; y1_flat = []; z1_flat = []; 
                            % --- Flatten the coordinates for vectorized distance calculations
                            x1_flat         = tube_surfs{j}{k}.XData(:);
                            y1_flat         = tube_surfs{j}{k}.YData(:);
                            z1_flat         = tube_surfs{j}{k}.ZData(:);
                            % --- Calculate distance to the sphere center
                            distance_matrix = sqrt((x1_flat - xc).^2 +...
                                                (y1_flat - yc).^2 +...
                                                (z1_flat - zc).^2);
                            % --- Find points inside the sphere at intersection point
                            inside_sphere   = distance_matrix <= radi_threshold;
                            x_out           = x1_flat(inside_sphere);
                            y_out           = y1_flat(inside_sphere);
                            z_out           = z1_flat(inside_sphere);
                            % --- Plot the intersecting points
                            tube_surfs{j}{k}.XData(any(inside_sphere, 2)) = NaN;
                            tube_surfs{j}{k}.YData(any(inside_sphere, 2)) = NaN;
                            tube_surfs{j}{k}.ZData(any(inside_sphere, 2)) = NaN;
                            % --- DEBUG PLOT: Plotting all deleted points
                            % plot3(x_out, y_out, z_out, 'cx', 'MarkerSize', 8);
                        end
                    end

                    % 5.2 - EMPTY PORTS INSIDE THE MAIN-CHAMBER
                    for j = 1:size(tube_surfs{i},2)
                        % -- Focal-point of the main chamber
                        x0     =  FP{fp_idx(1)}(1);
                        y0     =  FP{fp_idx(1)}(2);
                        z0     =  FP{fp_idx(1)}(3);
                        % -- Coordinates for port tube
                        x_port_flat = []; y_port_flat = []; z_port_flat = []; 
                        x_port_flat     = tube_surfs{i}{j}.XData(:);
                        y_port_flat     = tube_surfs{i}{j}.YData(:);
                        z_port_flat     = tube_surfs{i}{j}.ZData(:);
                        if strcmpi(MainBody, "Cylindrical")
                            % -- Calculate distances
                            xy_distances    = sqrt((x_port_flat - x0).^2 + (y_port_flat - y0).^2);
                            z_distances     = sqrt((z_port_flat - z0).^2);
                            % -- Calculate logical values
                            radi_threshold      = 0.5*OuterDiameter;
                            length_threshold    = 0.5*LengthMainBody;
                            % --- Find points inside the main body
                            inside_body     = (xy_distances < radi_threshold) & (z_distances < length_threshold);
                            x_out = x_port_flat(inside_body);
                            y_out = y_port_flat(inside_body);
                            z_out = z_port_flat(inside_body);
                            % --- Remove the points
                            tube_surfs{i}{j}.XData(any(inside_body, 2)) = NaN;
                            tube_surfs{i}{j}.YData(any(inside_body, 2)) = NaN;
                            tube_surfs{i}{j}.ZData(any(inside_body, 2)) = NaN;
                            % --- DEBUG PLOT: Plotting all deleted points
                            % plot3(x_out, y_out, z_out, 'g.', 'MarkerSize', 8);
                        elseif strcmpi(MainBody, "Spherical")
                            % -- Calculate distances
                            xyz_distances   = sqrt((x_port_flat - x0).^2 + (y_port_flat - y0).^2 + (z_port_flat - z0).^2);
                            % -- Calculate logical values
                            radi_threshold  = 0.5*OuterDiameter;
                            % --- Find points inside the main body
                            inside_body     = (xyz_distances < radi_threshold);
                            x_out = x_port_flat(inside_body);
                            y_out = y_port_flat(inside_body);
                            z_out = z_port_flat(inside_body);
                            % --- Plot the intersecting points
                            tube_surfs{i}{j}.XData(any(inside_body, 2)) = NaN;
                            tube_surfs{i}{j}.YData(any(inside_body, 2)) = NaN;
                            tube_surfs{i}{j}.ZData(any(inside_body, 2)) = NaN;
                            % --- DEBUG PLOT: Plotting all deleted points
                            % plot3(x_out, y_out, z_out, 'g.', 'MarkerSize', 8);
            
                        elseif strcmpi(MainBody, "Tetragonal")
                            % -- Calculate distances
                            x_distances   = sqrt((x_port_flat - x0).^2);
                            y_distances   = sqrt((y_port_flat - y0).^2);
                            z_distances   = sqrt((z_port_flat - z0).^2);
                            % -- Calculate logical values
                            radi_threshold      = 0.5*OuterDiameter;
                            length_threshold    = 0.5*LengthMainBody;
                            % --- Find points inside the main body
                            inside_body     = (x_distances < radi_threshold) & ((y_distances < radi_threshold)) & ((z_distances < length_threshold));
                            x_out = x_port_flat(inside_body);
                            y_out = y_port_flat(inside_body);
                            z_out = z_port_flat(inside_body);
                            % --- Plot the intersecting points
                            tube_surfs{i}{j}.XData(any(inside_body, 2)) = NaN;
                            tube_surfs{i}{j}.YData(any(inside_body, 2)) = NaN;
                            tube_surfs{i}{j}.ZData(any(inside_body, 2)) = NaN;
                            % --- DEBUG PLOT: Plotting all deleted points
                            % plot3(x_out, y_out, z_out, 'g.', 'MarkerSize', 8);

                        elseif strcmpi(MainBody, "Capsule")
                            % - Cylindrical shell
                            % -- Calculate distances
                            xy_distances    = sqrt((x_port_flat - x0).^2 + (y_port_flat - y0).^2);
                            z_distances     = sqrt((z_port_flat - z0).^2);
                            % -- Calculate logical values
                            radi_threshold      = 0.5*OuterDiameter;
                            length_threshold    = 0.5*LengthMainBody;
                            % --- Find points inside the main body
                            inside_body     = (xy_distances < radi_threshold) & (z_distances < length_threshold);
                            x_out = x_port_flat(inside_body);
                            y_out = y_port_flat(inside_body);
                            z_out = z_port_flat(inside_body);
                            % --- Plot the intersecting points
                            tube_surfs{i}{j}.XData(any(inside_body, 2)) = NaN;
                            tube_surfs{i}{j}.YData(any(inside_body, 2)) = NaN;
                            tube_surfs{i}{j}.ZData(any(inside_body, 2)) = NaN;
                            % --- DEBUG PLOT: Plotting all deleted points
                            % plot3(x_out, y_out, z_out, 'g.', 'MarkerSize', 8);

                            % - Spherical top
                            % -- Calculate distances
                            xyz_distances   = sqrt((x_port_flat - x0).^2 + (y_port_flat - y0).^2 + (z_port_flat - 0.5*LengthMainBody - z0).^2);
                            % -- Calculate logical values
                            radi_threshold  = 0.5*OuterDiameter;
                            % --- Find points inside the main body
                            inside_body     = (xyz_distances < radi_threshold);
                            x_out = x_port_flat(inside_body);
                            y_out = y_port_flat(inside_body);
                            z_out = z_port_flat(inside_body);
                            % --- Plot the intersecting points
                            tube_surfs{i}{j}.XData(any(inside_body, 2)) = NaN;
                            tube_surfs{i}{j}.YData(any(inside_body, 2)) = NaN;
                            tube_surfs{i}{j}.ZData(any(inside_body, 2)) = NaN;
                            % --- DEBUG PLOT: Plotting all deleted points
                            % plot3(x_out, y_out, z_out, 'c.', 'MarkerSize', 8);

                            % - Spherical bottom
                            % -- Calculate distances
                            xyz_distances   = sqrt((x_port_flat - x0).^2 + (y_port_flat - y0).^2 + (z_port_flat + 0.5*LengthMainBody - z0).^2);
                            % -- Calculate logical values
                            radi_threshold  = 0.5*OuterDiameter;
                            % --- Find points inside the main body
                            inside_body     = (xyz_distances < radi_threshold);
                            x_out = x_port_flat(inside_body);
                            y_out = y_port_flat(inside_body);
                            z_out = z_port_flat(inside_body);
                            % --- Plot the intersecting points
                            tube_surfs{i}{j}.XData(any(inside_body, 2)) = NaN;
                            tube_surfs{i}{j}.YData(any(inside_body, 2)) = NaN;
                            tube_surfs{i}{j}.ZData(any(inside_body, 2)) = NaN;
                            % --- DEBUG PLOT: Plotting all deleted points
                            % plot3(x_out, y_out, z_out, 'r.', 'MarkerSize', 8);
                        end
                    end

                else
                    % 5.3 - PARENT PORT-PORT INTERSECTIONS
                    x1_flat = []; y1_flat = []; z1_flat = []; 
                    x2_flat = []; y2_flat = []; z2_flat = []; 
                    % -- Coordinates for secondary port
                    x2_flat         = XLine_Port{i};
                    y2_flat         = YLine_Port{i};
                    z2_flat         = ZLine_Port{i};
                    % -- Coordinates for parent port
                    x1_flat         = tube_surfs{Parent_Port(i)}{2}.XData(:);
                    y1_flat         = tube_surfs{Parent_Port(i)}{2}.YData(:);
                    z1_flat         = tube_surfs{Parent_Port(i)}{2}.ZData(:);
                    % -- Finding the point on the main-body nearest the line
                    distance_matrix     = sqrt((x1_flat - x2_flat).^2 + ...
                                            (y1_flat - y2_flat).^2 + ...
                                            (z1_flat - z2_flat).^2);
                    % Find the minimum value in the matrix and its linear index 
                    [~, minIdx]         = min(distance_matrix(:)); 
                    [rowIdx, colIdx]    = ind2sub(size(distance_matrix), minIdx);
                    distance            = distance_matrix(rowIdx, colIdx);
                    % -- Defining point-of-intercept on the parent port
                    xc                  = x1_flat(rowIdx);
                    yc                  = y1_flat(rowIdx);
                    zc                  = z1_flat(rowIdx);
                    % -- Plotting points for debugging
                    flange_diameter     = regexp(Flange{i}, '\d+', 'match');
                    radi_threshold      = 0.45*str2double(flange_diameter{1});
                    % -- Executing removal of intersection points for all parent port surfaces
                    for j = 1:size(tube_surfs{i},2)
                        % -- Coordinates for parent port tube
                        x3_flat = []; y3_flat = []; z3_flat = []; 
                        x3_flat     = tube_surfs{Parent_Port(i)}{j}.XData(:);
                        y3_flat     = tube_surfs{Parent_Port(i)}{j}.YData(:);
                        z3_flat     = tube_surfs{Parent_Port(i)}{j}.ZData(:);
                        % --- Calculate distance to the sphere center
                        distance_matrix = sqrt((x3_flat - xc).^2 + (y3_flat - yc).^2 + (z3_flat - zc).^2);
                        % --- Find points inside the sphere
                        inside_sphere = distance_matrix <= radi_threshold;
                        x_out   = x3_flat(inside_sphere);
                        y_out   = y3_flat(inside_sphere);
                        z_out   = z3_flat(inside_sphere);
                        % --- Remove the parent port points
                        tube_surfs{Parent_Port(i)}{j}.XData(any(inside_sphere, 2)) = NaN;
                        tube_surfs{Parent_Port(i)}{j}.YData(any(inside_sphere, 2)) = NaN;
                        tube_surfs{Parent_Port(i)}{j}.ZData(any(inside_sphere, 2)) = NaN;
                        % % --- DEBUG PLOT: Plotting all deleted points
                        % plot3(x_out, y_out, z_out, 'c.', 'MarkerSize', 8);
                    end
                    % % -- DEBUG PLOT: Plotting port lines & centre of intersection
                    % plot3(x2_flat, y2_flat, z2_flat, 'b--', 'linewidth', 2);
                    % plot3(xc, yc, zc, 'ko', 'linewidth', 3, 'markersize', 15);

                    % 5.4 - PORT-TO-PARENT INTERSECTIONS
                    for j = 1:size(tube_surfs{i},2)
                        x1_flat = []; y1_flat = []; z1_flat = []; 
                        x2_flat = []; y2_flat = []; z2_flat = []; 
                        % -- Coordinates for parent 
                        x2_flat     = XLine_Port{Parent_Port(i)};
                        y2_flat     = YLine_Port{Parent_Port(i)};
                        z2_flat     = ZLine_Port{Parent_Port(i)};
                        % -- Coordinates for port tube
                        x1_flat     = tube_surfs{i}{j}.XData(:);
                        y1_flat     = tube_surfs{i}{j}.YData(:);
                        z1_flat     = tube_surfs{i}{j}.ZData(:);
                        % -- Calculate distances
                        distance_matrix    = sqrt((x1_flat - x2_flat).^2 + ...
                                                (y1_flat - y2_flat).^2 + ...
                                                (z1_flat - z2_flat).^2);
                        % -- Extract the flange diameter
                        flange_diameter = regexp(Flange{Parent_Port(i)}, '\d+', 'match');
                        flange_diameter = str2double(flange_diameter{1});
                        % -- Find points inside the main body
                        inside_body     = (distance_matrix < 0.45*flange_diameter);
                        x_out           = x1_flat(any(inside_body, 2));
                        y_out           = y1_flat(any(inside_body, 2));
                        z_out           = z1_flat(any(inside_body, 2));
                        % -- Plot the intersecting points
                        tube_surfs{i}{j}.XData(any(inside_body, 2)) = NaN;
                        tube_surfs{i}{j}.YData(any(inside_body, 2)) = NaN;
                        tube_surfs{i}{j}.ZData(any(inside_body, 2)) = NaN;
                        % % --- DEBUG PLOT: Plotting all deleted points
                        % plot3(x_out, y_out, z_out, 'g.', 'MarkerSize', 8);
                    end

                end
            end


            % % 5.5 - PORT-TO-PORT CONTAINERS
            % for i = 1:app.num_of_ports
            %     val_idx = (i+1):1:app.num_of_ports;
            %     for j = val_idx
            %         for k = 1:size(tube_surfs{i},2)
            %             % -- Coordinates for parent 
            %             x1_flat = []; y1_flat = []; z1_flat = []; 
            %             x1_flat         = tube_surfs{i}{k}.XData(:);
            %             y1_flat         = tube_surfs{i}{k}.YData(:);
            %             z1_flat         = tube_surfs{i}{k}.ZData(:);
            %             % -- Coordinates for port tube
            %             x2_flat = []; y2_flat = []; z2_flat = []; 
            %             x2_flat         = tube_surfs{j}{k}.XData(:);
            %             y2_flat         = tube_surfs{j}{k}.YData(:);
            %             z2_flat         = tube_surfs{j}{k}.ZData(:);
            %             xy2_distances   = sqrt((x2_flat).^2 + (y2_flat).^2);
            %             z2_distances    = sqrt((z2_flat).^2);
            %             % --- Find points inside the parent port
            %             flange_diameter     = regexp(Flange{i}, '\d+', 'match');
            %             radi_threshold      = 0.45*str2double(flange_diameter{1});
            %             length_threshold    = Length(i);
            %             inside_body         = (xy2_distances < radi_threshold) & (z2_distances < length_threshold);
            %             % --- Find points inside the main body
            %             x_out               = x2_flat(any(inside_body, 2));
            %             y_out               = y2_flat(any(inside_body, 2));
            %             z_out               = z2_flat(any(inside_body, 2));
            %             % -- Plot the intersecting points
            %             tube_surfs{j}{k}.XData(any(inside_body, 2)) = NaN;
            %             tube_surfs{j}{k}.YData(any(inside_body, 2)) = NaN;
            %             tube_surfs{j}{k}.ZData(any(inside_body, 2)) = NaN;
            %             % --- DEBUG PLOT: Plotting all deleted points
            %             plot3(x_out, y_out, z_out, 'gx', 'MarkerSize', 8);
            %         end
            %     end
            % end

            % 6 - Plotting the surfaces
            % 6.1 - Main Chamber Surfaces
            for i = 1:length(main_surfs)
                surf(main_surfs{i}.XData, main_surfs{i}.YData, main_surfs{i}.ZData);
            end
            % 6.2 - Flange Surfaces
            for i = 1:length(flange_surfs)
                for j = 1:length(flange_surfs{i})
                    surf(flange_surfs{i}{j}.XData, flange_surfs{i}{j}.YData, flange_surfs{i}{j}.ZData);
                end
                % --- Add labels for rotatable and threaded flanges
                theta   = deg2rad(Theta(i));
                phi     = deg2rad(Phi(i));
                % -- Displace flange to the end of the port
                x_shift     =  1.02*Length(i).*sin(pi/2-theta).*cos(phi) + FP{fp_idx(i)}(1);
                y_shift     =  1.02*Length(i).*sin(pi/2-theta).*sin(phi) + FP{fp_idx(i)}(2);
                z_shift     =  1.02*Length(i).*cos(pi/2-theta) + FP{fp_idx(i)}(3);
                if strcmpi(Rotatable{i}, "yes") && strcmpi(Threaded{i}, "yes")
                    text(x_shift, y_shift, z_shift, "R&T", 'FontSize',12, 'HorizontalAlignment','center', 'VerticalAlignment','middle');
                elseif strcmpi(Rotatable{i}, "yes")
                    text(x_shift, y_shift, z_shift, "R", 'FontSize',12, 'HorizontalAlignment','center', 'VerticalAlignment','middle');
                elseif strcmpi(Threaded{i}, "yes")
                    text(x_shift, y_shift, z_shift, "T", 'FontSize',12, 'HorizontalAlignment','center', 'VerticalAlignment','middle');
                end
            end
            % 6.3 - Port Tube Surfaces
            for i = 1:length(tube_surfs)
                for j = 1:length(tube_surfs{i})
                    surf(tube_surfs{i}{j}.XData, tube_surfs{i}{j}.YData, tube_surfs{i}{j}.ZData);
                end
            end
            
            % 7 - Extracting the best axes limits for the plot
            % --- Get all surface objects
            surfaces = findall(gcf, 'Type', 'Surface');
            % --- Set the face alpha transparency for all surfaces
            XData = []; YData = []; ZData = []; 
            for k = 1:length(surfaces)
                XData = vertcat(XData, surfaces(k).XData);
                YData = vertcat(YData, surfaces(k).YData);
                ZData = vertcat(ZData, surfaces(k).ZData);
            end
            minX = min(XData(:)); maxX = max(XData(:));
            minY = min(YData(:)); maxY = max(YData(:));
            minZ = min(ZData(:)); maxZ = max(ZData(:));
            app.plot_axes_lims = [minX, maxX, minY, maxY, minZ, maxZ];

            % 8 - Plot the axes
            plot3([0 1.2*maxX], [0 0], [0 0], 'k', 'LineWidth', 2); % X-axis in black
            plot3([0 0], [0 1.2*maxY], [0 0], 'k', 'LineWidth', 2); % Y-axis in black
            plot3([0 0], [0 0], [0 1.2*maxZ], 'k', 'LineWidth', 2); % Z-axis in black
            % -- Setting the labels
            text(1.22*maxX, 0, 0, 'X', 'Color', 'k', 'FontSize', 12);
            text(0, 1.22*maxY, 0, 'Y', 'Color', 'k', 'FontSize', 12);
            text(0, 0, 1.22*maxZ, 'Z', 'Color', 'k', 'FontSize', 12);
            % -- Plot the focal points
            for i = 1:app.num_of_focalpoints
                plot3(FP{i}(1), FP{i}(2), FP{i}(3), 'ks', 'color', FP_cols(i,:), 'MarkerFaceColor', FP_cols(i,:), 'markersize', 15);
                line([minX, maxX]+FP{i}(1), [0,0]+FP{i}(2), [0,0]+FP{i}(3), 'LineStyle', ':', 'LineWidth', 1, 'Color', FP_cols(i,:));
                line([0,0]+FP{i}(1), [minY, maxY]+FP{i}(2), [0,0]+FP{i}(3), 'LineStyle', ':', 'LineWidth', 1, 'Color', FP_cols(i,:));
                line([0,0]+FP{i}(1), [0,0]+FP{i}(2), [minZ, maxZ]+FP{i}(3), 'LineStyle', ':', 'LineWidth', 1, 'Color', FP_cols(i,:));
            end

            % 9 - Formatting the figure
            % Set the surface color to gray
            colormap(app.plot_render_color);
            % Edit the transparency of all surfaces
            % --- Get all surface objects
            surfaces = findall(gcf, 'Type', 'Surface');
            % --- Set the face alpha transparency for all surfaces
            for k = 1:length(surfaces)
                if strcmpi(app.plot_transparency, "25%");                   surfaces(k).FaceAlpha = 0.25; 
                elseif strcmpi(app.plot_transparency, "50%");               surfaces(k).FaceAlpha = 0.50; 
                elseif strcmpi(app.plot_transparency, "75%");               surfaces(k).FaceAlpha = 0.75; 
                elseif strcmpi(app.plot_transparency, "100% (Opaque)%");    surfaces(k).FaceAlpha = 1.0; 
                end
            end
            % Add shading to enhance the 3D effect
            if strcmpi(app.plot_render_type, "Metal"); material metal; shading interp;
            elseif strcmpi(app.plot_render_type, "Shiny"); material shiny; shading interp; 
            elseif strcmpi(app.plot_render_type, "Dull"); material dull; shading interp; 
            elseif strcmpi(app.plot_render_type, "Faceted"); shading faceted;
            end
            % Add lighting for better contrast 
            % camlight headlight;
            camlight left;
            camlight right;
            % camlight(45, 45);
            lighting gouraud;
        end

        % Plot final chamber (1-panel figure)
        function view_final_chamber_1panel(app)
            % Initialising the 1-panel figure
            fig = figure();
            fig.Name = 'SnapVac3D - Snapshot : 3D viewer';
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 510;
            fig.Position(4) = 510;
            % Create a tiled layout with 1 row and 1 column
            t = tiledlayout(1, 1, 'TileSpacing', 'compact', 'Padding', 'compact');
            title(t, app.myData.FileName, 'interpreter','none', 'FontWeight', 'bold');
            nexttile; hold on;
            extract_and_plot_final_model(app);
            % - Formatting the axis
            axis equal; 
            axis off; set(gca, 'Color', 'none'); grid off;
            view([-222,32]); 
            % set(gca, 'Position', [0 0 1. 1.]); % Removes any white space around the axes
            axis(app.plot_axes_lims);
        end

        % Plot final chamber (4-panel figure)
        function view_final_chamber_4panel(app)
            % Initialising the 4-panel figure
            fig = figure();
            fig.Name = 'SnapVac3D - Snapshot : 4-panel viewer';
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2)-50;
            fig.Position(3) = 600;
            fig.Position(4) = 600;
            vpad = 10;

            % Create a tiled layout with 2 rows and 2 columns
            t = tiledlayout(2, 2, 'TileSpacing', 'compact', 'Padding', 'compact');
            title(t, app.myData.FileName, 'interpreter','none', 'FontWeight', 'bold');
            
            % 3D plot of the vacuum chamber
            nexttile; hold on;
            extract_and_plot_final_model(app);
            % - Formatting the axis
            title('3D View');
            axis equal; 
            axis off; set(gca, 'Color', 'none'); grid off;
            view([-222,32]);
            % -- Pad the axes limits
            axis(app.plot_axes_lims);
            
            % X-Y plane
            nexttile; hold on;
            extract_and_plot_final_model(app);
            title('X-Y Plane');
            xlabel('X (mm)', 'FontWeight', 'bold');
            ylabel('Y (mm)', 'FontWeight', 'bold');
            zlabel('Z (mm)', 'FontWeight', 'bold');
            axis equal; grid on;
            view(0, 90); camlight(0, 45);
            xlim([app.plot_axes_lims(1)-vpad, app.plot_axes_lims(2)+vpad]); 
            ylim([app.plot_axes_lims(3)-vpad, app.plot_axes_lims(4)+vpad]); 
            zlim([app.plot_axes_lims(5)-vpad, app.plot_axes_lims(6)+vpad]); 
            
            % Z-Y plane
            nexttile; hold on;
            extract_and_plot_final_model(app);
            title('Z-Y Plane');
            xlabel('X (mm)', 'FontWeight', 'bold');
            ylabel('Y (mm)', 'FontWeight', 'bold');
            zlabel('Z (mm)', 'FontWeight', 'bold');
            axis equal; grid on;
            view(90, 0); camlight(45, 0);
            xlim([app.plot_axes_lims(1)-vpad, app.plot_axes_lims(2)+vpad]); 
            ylim([app.plot_axes_lims(3)-vpad, app.plot_axes_lims(4)+vpad]); 
            zlim([app.plot_axes_lims(5)-vpad, app.plot_axes_lims(6)+vpad]); 
            
            % Z-X plane
            nexttile; hold on;
            extract_and_plot_final_model(app);
            title('Z-X Plane');
            xlabel('X (mm)', 'FontWeight', 'bold');
            ylabel('Y (mm)', 'FontWeight', 'bold');
            zlabel('Z (mm)', 'FontWeight', 'bold');
            axis equal; grid on;
            view(0, 0); camlight(-45, 0);
            xlim([app.plot_axes_lims(1)-vpad, app.plot_axes_lims(2)+vpad]); 
            ylim([app.plot_axes_lims(3)-vpad, app.plot_axes_lims(4)+vpad]); 
            zlim([app.plot_axes_lims(5)-vpad, app.plot_axes_lims(6)+vpad]); 
        end

        % Plot final chamber (live view)
        function view_final_chamber_live(app)
            figHandle = 3633;
            fig = findobj('Type', 'figure', 'Number', figHandle);
            if ~isempty(fig); close(figHandle); end
            % Check if the figure exists
            fig = figure(figHandle);
            fig.Name = 'SnapVac3D - Live Viewer';
            fig.Position(1) = app.UIFigure.Position(1)+app.UIFigure.Position(3);
            fig.Position(2) = app.UIFigure.Position(2);
            fig.Position(3) = 510;
            fig.Position(4) = 510;
            hold on;
            % Plot the model
            extract_and_plot_final_model(app);
            % - Formatting the axis
            % title(app.myData.FileName, 'interpreter','none', 'FontWeight', 'bold');
            axis equal; 
            axis off; set(gca, 'Color', 'none'); grid off;
            view([-222,32]); 
            set(gca, 'Position', [0 0 1. 1.]); % Removes any white space around the axes
            axis(app.plot_axes_lims);
        end

        % Update the plot for the final chamber (live view)
        function update_final_chamber_live(app)
            figHandle = 3633;
            % Check if the figure exists
            fig = findobj('Type', 'figure', 'Number', figHandle);
            % If the figure exists, then update
            if ~isempty(fig)
                ax = gca; [az, el] = view(ax);
                cla(); clf(); hold on;
                % Plot the model
                extract_and_plot_final_model(app);
                % - Formatting the axis
                % title(app.myData.FileName, 'interpreter','none', 'FontWeight', 'bold');
                axis equal; 
                axis off; set(gca, 'Color', 'none'); grid off;
                view([az, el]); 
                set(gca, 'Position', [0 0 1. 1.]); % Removes any white space around the axes
                axis(app.plot_axes_lims);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'SnapVac3D App (procopios.constantinou@psi.ch)';
            % 1 - Initialising App Properties
            app.myData                              = struct();
            app.color_of_focalpoints                = lines(24);
            % 1.1 - Chamber Properties
            default_filename                        = char(string(yyyymmdd(datetime))+"_PSI_VacBuild");
            body_types                              = extract_body_types(app);
            app.Chamber_UITable.ColumnFormat        = {'char', body_types, 'numeric', 'numeric', 'numeric', 'char'};
            app.Chamber_UITable.ColumnName          = {'Name', 'Main-Body Type', 'Outer Diameter (mm)', 'Wall Thickness (mm)', 'Height (mm)', 'Notes'};
            app.Chamber_UITable.ColumnEditable      = [true,true,true,true,true,true];
            app.Chamber_UITable.Data                = {default_filename,body_types{1},50,2,100,''};
            app.table_chamber                       = array2table(app.Chamber_UITable.Data, 'VariableNames', app.Chamber_UITable.ColumnName);
            % 1.2 - Focal-Point Properties
            app.num_of_focalpoints                  = 1;
            % app.FocalPoint_UITable.ColumnWidth      = {'fit', 'fit', 'auto', 'auto', 'auto'};
            app.FocalPoint_UITable.ColumnFormat     = {'char', 'char', 'numeric', 'numeric', 'numeric'};
            app.FocalPoint_UITable.ColumnName       = {'Name', 'Color', 'Fx (mm)', 'Fy (mm)', 'Fz (mm)'};
            app.FocalPoint_UITable.ColumnEditable   = [false,false,true,true,true];
            app.FocalPoint_UITable.Data             = {'A','',0,0,0};
            app.table_focalpoints                   = array2table(app.FocalPoint_UITable.Data, 'VariableNames', app.FocalPoint_UITable.ColumnName);
            % -- Updating the color cell
            temp_col = uistyle('BackgroundColor', app.color_of_focalpoints(1,:)); addStyle(app.FocalPoint_UITable, temp_col, 'cell', [1, 2]);
            % 1.3 - Port Properties
            app.num_of_ports                        = 1;
            flange_types                            = extract_flange_types(app);
            binary_types                            = extract_binary_types(app);
            focal_types                             = extract_focal_types(app);
            app.Port_UITable.ColumnFormat           = {'numeric', flange_types, 'numeric', 'numeric', 'numeric', binary_types, binary_types, 'numeric', focal_types};
            app.Port_UITable.ColumnName             = {'Port', 'Flange', 'Length', 'Theta (β)', 'Phi (α)', 'Rotatable?', 'Threaded?', 'Usage', 'Focal-Point'};
            app.Port_UITable.ColumnEditable         = [false,true,true,true,true,true,true,true,true];
            app.Port_UITable.Data                   = {1,flange_types{2},100,0,0,binary_types{2},binary_types{2},'',focal_types{1}};
            app.table_ports                         = array2table(app.Port_UITable.Data, 'VariableNames', app.Port_UITable.ColumnName);
            % -- Updating the colors of the focal points
            temp_col = uistyle('BackgroundColor', [app.color_of_focalpoints(1,:)], 'FontWeight','bold', 'HorizontalAlignment','center'); addStyle(app.Port_UITable, temp_col, 'cell', [1, 9]);
            % 2 - Plot Properties
            % -- Render / Plot Type
            plot_render_types               = extract_plot_render_types(app);
            app.RenderType_DropDown.Items   = plot_render_types;
            app.RenderType_DropDown.Value   = plot_render_types{1};
            app.plot_render_type            = app.RenderType_DropDown.Value;
            app.RenderColor_ColorPicker.Value   = [0.80,0.80,0.80];
            app.plot_render_color               = app.RenderColor_ColorPicker.Value;
            % -- Transparency / Alpha Value
            plot_transparency_types = extract_plot_transparency_types(app);
            app.TransparencyType_DropDown.Items   = plot_transparency_types;
            app.TransparencyType_DropDown.Value   = plot_transparency_types{3};
            app.plot_transparency           = app.TransparencyType_DropDown.Value;
            % -- Number of Points
            app.num_of_points               = 60;
            % 3 - Editing the color of the main buttons
            add_color       = [0.7, 0.9, 0.7];
            remove_color    = [0.9, 0.7, 0.7];
            misc_color      = [0.7, 0.7, 0.9];
            app.Port_AutoSort_Button.BackgroundColor        = misc_color;
            app.FocalPoint_Add_Button.BackgroundColor       = add_color;
            app.Port_Add_Button.BackgroundColor             = add_color;
            app.FocalPoint_Delete_Button.BackgroundColor    = remove_color;
            app.Port_Delete_Button.BackgroundColor          = remove_color;
            app.Port_DeleteRowN_Button.BackgroundColor      = remove_color;
            app.ViewLive_Button.BackgroundColor             = [0.9, 0.9, 0.9];
            app.ViewModel_1Panel_Button.BackgroundColor     = [0.8, 0.8, 0.8];
            app.ViewModel_4Panel_Button.BackgroundColor     = [0.8, 0.8, 0.8];
            % 4 - Update myData structure
            update_myData(app);
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            % Remove the UI handle and restart
            close(gcbf); run App_SnapVac3D.mlapp;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 1170;
            screen_size(4) = 555;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: LOAD_Button
        function LOAD_ButtonPushed(app, event)
            warning('off', 'all');
            % 1 - Loading in the FileName and PathName of the selected data
            [FileName, PathName] = uigetfile({'*.txt'}, 'Choose a SnapVac3D *.txt data file...');
            load_fullfile = char(string(PathName) + string(FileName));
            % - If Cancel is pressed, then return nothing
            if isequal(PathName,0) || isequal(FileName,0); return; end
            % 2 - Executing the loading of the data
            % -- Read the file line by line and find the indicator
            fid     = fopen(load_fullfile);
            data    = textscan(fid, '%s', 'Delimiter', '\n');
            fclose(fid);
            lines   = data{1};
            idx = {};
            for i = 1:length(lines); if strcmp(lines{i}, '-----'); idx{end+1} = i; end; end
            % -- Extract the tables
            % --- Load in Main-Body Table Information
            opts = detectImportOptions(load_fullfile); 
            opts.DataLines = [2 idx{1}-1];          
            opts.VariableNamesLine = 1;
            firstColumnName = opts.VariableNames{1};            % Get the name of the first column 
            opts = setvartype(opts, firstColumnName, 'char');   % Make sure the first entry is a char
            T1 = readtable(load_fullfile, opts);
            T1(:,7:end) = [];
            % --- Load in Focal-Points Table Information
            opts = detectImportOptions(load_fullfile); 
            opts.DataLines = [idx{1}+2 idx{2}-1];      
            opts.VariableNamesLine = idx{1}+1;
            firstColumnName = opts.VariableNames{1};            % Get the name of the first column 
            opts = setvartype(opts, firstColumnName, 'char');   % Make sure the first entry is a char
            T2 = readtable(load_fullfile, opts);
            T2(:,6:end) = [];
            % --- Load in Ports Table Information
            opts = detectImportOptions(load_fullfile); 
            opts.DataLines = [idx{2}+2 idx{3}-1];      
            opts.VariableNamesLine = idx{2}+1;
            T3 = readtable(load_fullfile, opts);
            % 3 - Updating the tables within the App
            app.num_of_focalpoints  = size(T2, 1);
            app.num_of_ports        = size(T3, 1);
            % 3.1 - Main-Body Table
            app.Chamber_UITable.Data = {T1{1,1}{1},T1{1,2}{1},T1{1,3},T1{1,4},T1{1,5},T1{1,6}{1}};
            app.table_chamber       = array2table(app.Chamber_UITable.Data, 'VariableNames', app.Chamber_UITable.ColumnName);
            % 3.2 - Focal-Point Table
            focal_types     = extract_focal_types(app);
            for i = 1:app.num_of_focalpoints
                app.FocalPoint_UITable.Data(i,:) = {focal_types{i},T2{i,2}{1},T2{i,3},T2{i,4},T2{i,5}};
                temp_col = uistyle('BackgroundColor', app.color_of_focalpoints(i,:)); addStyle(app.FocalPoint_UITable, temp_col, 'cell', [i, 2]);
            end
            if size(app.FocalPoint_UITable.Data,1) > app.num_of_focalpoints
                app.FocalPoint_UITable.Data(app.num_of_focalpoints+1:end,:) = [];
            end
            app.table_focalpoints       = array2table(app.FocalPoint_UITable.Data, 'VariableNames', app.FocalPoint_UITable.ColumnName);
            % 3.3 - Ports Table
            for i = 1:app.num_of_ports
                app.Port_UITable.Data(i,:) = {i,T3{i,2}{1},T3{i,3},T3{i,4},T3{i,5},T3{i,6}{1},T3{i,7}{1},T3{i,8}{1},T3{i,9}{1}};
            end
            if size(app.Port_UITable.Data,1) > app.num_of_ports
                app.Port_UITable.Data(app.num_of_ports+1:end,:) = [];
            end
            for i = 1:app.num_of_ports
                [~, fp_idx] = ismember(app.Port_UITable.Data{i,9}, focal_types);
                temp_col = uistyle('BackgroundColor', [app.color_of_focalpoints(fp_idx,:)], 'FontWeight','bold', 'HorizontalAlignment','center'); addStyle(app.Port_UITable, temp_col, 'cell', [i, 9]);
            end
            app.table_ports             = array2table(app.Port_UITable.Data, 'VariableNames', app.Port_UITable.ColumnName);
            % -- Update table properties
            flange_types                            = extract_flange_types(app);
            binary_types                            = extract_binary_types(app);
            focal_types                             = extract_focal_types(app);
            app.Port_UITable.ColumnFormat           = {'numeric', flange_types, 'numeric', 'numeric', 'numeric', binary_types, binary_types, 'numeric', focal_types};
            % 4 - Update myData structure
            update_myData(app);
            warning('on', 'all');
            % - Update the live figure if required
            update_final_chamber_live(app);
        end

        % Button pushed function: SAVEAS_Button
        function SAVEAS_ButtonPushed(app, event)
            % 1 - User defined FileName and Path for the processed data
            fname = app.myData.FileName; filter = {'*.txt'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the design...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...');
            % 2.1 - Assigning each table to a T variable
            T1 = app.myData.table_chamber;
            T2 = app.myData.table_focalpoints;
            T3 = app.myData.table_ports;
            % 2.2 - Appending the data-tables to a text file
            % -- Write the first table to the file (overwrite if the file exists)
            writetable(T1, save_fullfile, 'Delimiter', '\t', 'WriteVariableNames', true);
            % -- Append the separator
            fid = fopen(save_fullfile, 'a');
            fprintf(fid, '-----');
            fclose(fid);
            % -- Append the second table to the file
            writetable(T2, save_fullfile, 'Delimiter', '\t', 'WriteMode', 'append', 'WriteVariableNames', true);
            % -- Append the separator
            fid = fopen(save_fullfile, 'a');
            fprintf(fid, '-----');
            fclose(fid);
            % -- Append the third table to the file
            writetable(T3, save_fullfile, 'Delimiter', '\t', 'WriteMode', 'append', 'WriteVariableNames', true);
            % -- Append the separator
            fid = fopen(save_fullfile, 'a');
            fprintf(fid, '-----');
            fclose(fid);
            % 3 - Executing the saving of a png
            % -- Save 4-panel figure
            view_final_chamber_1panel(app);
            saveas(gcf,save_fullfile(1:end-4) + "_3D", 'png');
            % -- Save 3D figure
            view_final_chamber_4panel(app);
            saveas(gcf,save_fullfile(1:end-4) + "_4-panel", 'png');
            % 4 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); close(wbar);
        end

        % Cell edit callback: Chamber_UITable
        function Chamber_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - General Validity check on the input values
            if indices(2) == 3; if newData < 0; newData = abs(newData); end; end
            if indices(2) == 3; if isnan(newData); newData = 0; end; end
            if indices(2) == 4; if newData < 0; newData = abs(newData); end; end
            if indices(2) == 4; if isnan(newData); newData = 0; end; end
            if indices(2) == 5; if newData < 0; newData = abs(newData); end; end
            if indices(2) == 5; if isnan(newData); newData = 0; end; end
            % 3 - Assigning the output
            app.Chamber_UITable.Data{indices(1),indices(2)} = newData;
            app.table_chamber       = array2table(app.Chamber_UITable.Data, 'VariableNames', app.Chamber_UITable.ColumnName);
            % 4 - Printing the output
            if indices(2) == 1;     update_txt = sprintf("-> Name = %s \n", newData);
            elseif indices(2) == 2; update_txt = sprintf("-> Main-Body Type = %s \n", newData);
            elseif indices(2) == 3; update_txt = sprintf("-> Outer-Diameter = %.2f mm \n", newData);
            elseif indices(2) == 4; update_txt = sprintf("-> Wall Thickness = %.2f mm \n",  newData);
            elseif indices(2) == 5; update_txt = sprintf("-> Length = %.2f mm \n",  newData);
            elseif indices(2) == 6; update_txt = sprintf("-> Notes = %s \n",  newData);
            end
            fprintf(update_txt);
            update_myData(app);
            % - Update the live figure if required
            update_final_chamber_live(app);
        end

        % Button pushed function: FocalPoint_Add_Button
        function FocalPoint_Add_ButtonPushed(app, event)
            % - Adding one new focal-point to the UI table
            if app.num_of_focalpoints ~= 24
                app.num_of_focalpoints      = app.num_of_focalpoints+1;
                focal_types                 = extract_focal_types(app);
                % -- Updating the Focal-Point Values UI Table
                Name                        = focal_types{end};
                Colour                      = '';
                FPX                         = app.FocalPoint_UITable.Data(end,3); FPX = FPX{1};
                FPY                         = app.FocalPoint_UITable.Data(end,4); FPY = FPY{1};
                FPZ                         = app.FocalPoint_UITable.Data(end,5); FPZ = FPZ{1};
                app.FocalPoint_UITable.Data(end+1,:) = {Name, Colour, FPX, FPY, FPZ};
                % -- Updating the color cell
                temp_col = uistyle('BackgroundColor', app.color_of_focalpoints(app.num_of_focalpoints,:)); 
                addStyle(app.FocalPoint_UITable, temp_col, 'cell', [app.num_of_focalpoints, 2]);
                % -- Updating the Focal-Point Values in the Port table
                focal_types                             = extract_focal_types(app);
                app.Port_UITable.ColumnFormat{9}        = focal_types;
                % -- Updating the focal-point color cell of port table
                focal_types = extract_focal_types(app);
                for i = 1:app.num_of_ports
                    [~, fp_idx] = ismember(app.Port_UITable.Data{i,9}, focal_types);
                    temp_col = uistyle('BackgroundColor', [app.color_of_focalpoints(fp_idx,:)], 'FontWeight','bold', 'HorizontalAlignment','center'); addStyle(app.Port_UITable, temp_col, 'cell', [i, 9]);
                end
                % -- Assigning and printing the output
                app.table_focalpoints       = array2table(app.FocalPoint_UITable.Data, 'VariableNames', app.FocalPoint_UITable.ColumnName);
                fprintf("-> Focal-Point Added: N = " + string(app.num_of_focalpoints) + " \n");
            else; fprintf("-> Currently only supports 24 focal-points maximum...\n");
            end
            % - Update data-structure
            update_myData(app);
            % - Update the live figure if required
            update_final_chamber_live(app);
        end

        % Button pushed function: FocalPoint_Delete_Button
        function FocalPoint_Delete_ButtonPushed(app, event)
            % - Removing one focal-point from the UI tables
            if app.num_of_focalpoints ~= 1 
                app.num_of_focalpoints = app.num_of_focalpoints - 1;
                app.FocalPoint_UITable.Data(end,:) = []; 
                % -- Updating the Focal-Point Values in the Port table
                focal_types                             = extract_focal_types(app);
                app.Port_UITable.ColumnFormat{9}        = focal_types;
                for i = 1:app.num_of_ports
                    if ~ismember(string(app.Port_UITable.Data{i,9}),focal_types)
                        app.Port_UITable.Data{i,9} = focal_types{1};
                    end
                end
                app.table_ports = array2table(app.Port_UITable.Data, 'VariableNames', app.Port_UITable.ColumnName);
                % -- Updating the focal-point color cell of port table
                focal_types = extract_focal_types(app);
                for i = 1:app.num_of_ports
                    [~, fp_idx] = ismember(app.Port_UITable.Data{i,9}, focal_types);
                    temp_col = uistyle('BackgroundColor', [app.color_of_focalpoints(fp_idx,:)], 'FontWeight','bold', 'HorizontalAlignment','center'); addStyle(app.Port_UITable, temp_col, 'cell', [i, 9]);
                end
                % -- Assigning and printing the output
                app.table_focalpoints       = array2table(app.FocalPoint_UITable.Data, 'VariableNames', app.FocalPoint_UITable.ColumnName);
                fprintf("-> Focal-Point Deleted: N = " + string(app.num_of_focalpoints) + " \n");
            else; fprintf("-> At least one focal-point is required...\n");
            end
            % - Update data-structure
            update_myData(app);
            % - Update the live figure if required
            update_final_chamber_live(app);
        end

        % Cell edit callback: FocalPoint_UITable
        function FocalPoint_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - General Validity check on the input values
            if isnan(newData); newData = 0; end
            % 3 - Assigning the output
            app.FocalPoint_UITable.Data{indices(1),indices(2)} = newData;
            app.table_focalpoints       = array2table(app.FocalPoint_UITable.Data, 'VariableNames', app.FocalPoint_UITable.ColumnName);
            % 4 - Printing the output
            focal_types = extract_focal_types(app);
            if indices(2) == 3;     update_txt = sprintf("-> FPX(%s) = %.2f mm \n", focal_types{indices(1)}, newData);
            elseif indices(2) == 4; update_txt = sprintf("-> FPY(%s) = %.2f mm \n", focal_types{indices(1)}, newData);
            elseif indices(2) == 5; update_txt = sprintf("-> FPZ(%s) = %.2f mm \n", focal_types{indices(1)}, newData);
            end
            fprintf(update_txt);
            update_myData(app);
            % - Update the live figure if required
            update_final_chamber_live(app);
        end

        % Button pushed function: Port_Add_Button
        function Port_Add_ButtonPushed(app, event)
            % - Adding one new port to the UI table
            app.num_of_ports    = app.num_of_ports+1;
            % -- Updating the Ports Values UI Table
            binary_types        = extract_binary_types(app);
            Port                = app.num_of_ports;
            Flange              = app.Port_UITable.Data(end,2); Flange = Flange{1};
            Length              = 100;
            Theta               = 0;
            Phi                 = 0;
            Rotatable           = binary_types{2}; 
            Threaded            = binary_types{2}; 
            Usage               = '';
            FP                  = app.Port_UITable.Data(end,9); FP = FP{1};
            app.Port_UITable.Data(end+1,:) = {Port, Flange, Length, Theta, Phi, Rotatable, Threaded, Usage, FP};
            % -- Updating the focal-point color cell
            focal_types = extract_focal_types(app);
            for i = 1:app.num_of_ports
                [~, fp_idx] = ismember(app.Port_UITable.Data{i,9}, focal_types);
                temp_col = uistyle('BackgroundColor', [app.color_of_focalpoints(fp_idx,:)], 'FontWeight','bold', 'HorizontalAlignment','center'); addStyle(app.Port_UITable, temp_col, 'cell', [i, 9]);
            end
            % -- Assigning and printing the output
            app.table_ports     = array2table(app.Port_UITable.Data, 'VariableNames', app.Port_UITable.ColumnName);
            fprintf("-> Port Added: N = " + string(app.num_of_ports) + " \n");
            % - Update data-structure
            update_myData(app);
            % - Update the live figure if required
            update_final_chamber_live(app);
        end

        % Button pushed function: Port_Delete_Button
        function Port_Delete_ButtonPushed(app, event)
            % - Removing one port from the UI tables
            if app.num_of_ports ~= 1 
                app.num_of_ports = app.num_of_ports - 1;
                app.Port_UITable.Data(end,:) = []; 
                % -- Assigning and printing the output
                app.table_ports       = array2table(app.Port_UITable.Data, 'VariableNames', app.Port_UITable.ColumnName);
                fprintf("-> Port Deleted: N = " + string(app.num_of_ports) + " \n");
            else; fprintf("-> At least one port is required...\n");
            end
            % - Update data-structure
            update_myData(app);
            % - Update the live figure if required
            update_final_chamber_live(app);
        end

        % Button pushed function: Port_DeleteRowN_Button
        function Port_DeleteRowN_ButtonPushed(app, event)
            % - Removing one port from the UI tables (defined index)
            if app.num_of_ports ~= 1 
                prompt      = {'Enter the Port Number you want to delete:'};
                dlgtitle    = 'Delete Entry';
                dims        = [1 50];
                answer      = inputdlg(prompt,dlgtitle,dims);
                if ~isempty(answer) 
                    user_val = str2num(answer{1});
                    if user_val > app.num_of_ports || user_val < 1;
                        fprintf("-> Must enter a Port Number between 1 - " + string(app.num_of_ports) + "\n");
                    else
                        app.Port_UITable.Data(user_val,:) = []; 
                        app.num_of_ports = app.num_of_ports - 1;
                        for i = 1:app.num_of_ports; app.Port_UITable.Data(i,1) = {i}; end
                        % -- Assigning and printing the output
                        app.table_ports       = array2table(app.Port_UITable.Data, 'VariableNames', app.Port_UITable.ColumnName);
                        fprintf("-> Port #" + string(user_val) + " Deleted \n");
                    end
                end
            else; fprintf("-> At least one port is required...\n");
            end
            % - Update data-structure
            update_myData(app);
            % - Update the live figure if required
            update_final_chamber_live(app);
        end

        % Cell edit callback: Port_UITable
        function Port_UITableCellEdit(app, event)
            % 1 - Extracting the user input fields
            indices = event.Indices;
            newData = event.NewData;
            % 2 - General Validity check on the input values
            if indices(2) == 3; if newData < 0; newData = abs(newData); end; end
            if indices(2) == 3; if isnan(newData); newData = 0; end; end
            % 3 - Assigning the output
            app.Port_UITable.Data{indices(1),indices(2)} = newData;
            app.table_ports       = array2table(app.Port_UITable.Data, 'VariableNames', app.Port_UITable.ColumnName);
            % 4 - Printing the output
            if indices(2) == 2;     update_txt = sprintf("-> (Port %i) Flange = %s \n", indices(1), newData);
            elseif indices(2) == 3; update_txt = sprintf("-> (Port %i) Length = %.2f mm \n", indices(1), newData);
            elseif indices(2) == 4; update_txt = sprintf("-> (Port %i) Theta = %.2f deg. \n", indices(1), newData);
            elseif indices(2) == 5; update_txt = sprintf("-> (Port %i) Phi = %.2f deg. \n", indices(1), newData);
            elseif indices(2) == 6; update_txt = sprintf("-> (Port %i) Rotatable = %s \n", indices(1), newData);
            elseif indices(2) == 7; update_txt = sprintf("-> (Port %i) Threaded = %s \n", indices(1), newData);
            elseif indices(2) == 8; update_txt = sprintf("-> (Port %i) Usage = %s \n", indices(1), newData);
            elseif indices(2) == 9
                update_txt = sprintf("-> (Port %i) Focal-Point = %s \n", indices(1), newData);
                % 5 - Updating the colors of the focal points
                focal_types = extract_focal_types(app);
                for i = 1:app.num_of_ports
                    [~, fp_idx] = ismember(app.Port_UITable.Data{i,9}, focal_types);
                    temp_col = uistyle('BackgroundColor', [app.color_of_focalpoints(fp_idx,:)], 'FontWeight','bold', 'HorizontalAlignment','center'); addStyle(app.Port_UITable, temp_col, 'cell', [i, 9]);
                end
            end
            fprintf(update_txt);
            update_myData(app);
            % - Update the live figure if required
            update_final_chamber_live(app);
        end

        % Button pushed function: Port_AutoSort_Button
        function Port_AutoSort_ButtonPushed(app, event)
            % 1 - Sort in ascending order of port lengths
            app.Port_UITable.Data   = sortrows(app.Port_UITable.Data, 3);
            % 2 - Sort in ascending order of flange sizes
            flange = [];
            for i = 1:app.num_of_ports
                flange_diameter = regexp(app.Port_UITable.Data{i,2}, '\d+', 'match');
                flange(i)       = str2double(flange_diameter{1});
            end
            [~, flange_idx]   = sort(flange);
            app.Port_UITable.Data   = app.Port_UITable.Data(flange_idx,:);
            % 3 - Sort in ascending order of focal-points
            app.Port_UITable.Data   = sortrows(app.Port_UITable.Data, 9);
            % 4 - Re-number the port numbers in ascending order
            for i = 1:app.num_of_ports; app.Port_UITable.Data{i,1} = i; end
            % 5 - Updating the colors of the focal points
            focal_types = extract_focal_types(app);
            for i = 1:app.num_of_ports
                [~, fp_idx] = ismember(app.Port_UITable.Data{i,9}, focal_types);
                temp_col = uistyle('BackgroundColor', [app.color_of_focalpoints(fp_idx,:)], 'FontWeight','bold', 'HorizontalAlignment','center'); addStyle(app.Port_UITable, temp_col, 'cell', [i, 9]);
            end
            % - Updating properties
            app.table_ports = array2table(app.Port_UITable.Data, 'VariableNames', app.Port_UITable.ColumnName);
            update_myData(app);
            % - Update the live figure if required
            update_final_chamber_live(app);
        end

        % Value changed function: RenderType_DropDown
        function RenderType_DropDownValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.RenderType_DropDown.Value;
            % 2 - Assigning the output
            app.plot_render_type = value;
            % 3 - Printing the output
            update_txt = sprintf("-> Render Type = %s \n", app.plot_render_type);
            fprintf(update_txt);
            update_myData(app);
            % - Update the live figure if required
            update_final_chamber_live(app);
        end

        % Callback function: RenderColor_ColorPicker
        function RenderColor_ColorPickerValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.RenderColor_ColorPicker.Value;
            % 2 - Assigning the output
            app.plot_render_color = value;
            % 3 - Printing the output
            update_txt = sprintf("-> Render Color = [%.2f, %.2f, %.2f] \n", app.plot_render_color(1), app.plot_render_color(2), app.plot_render_color(3));
            fprintf(update_txt);
            update_myData(app);
            % - Update the live figure if required
            update_final_chamber_live(app);
        end

        % Value changed function: TransparencyType_DropDown
        function TransparencyType_DropDownValueChanged(app, event)
            % 1 - Extracting the user input fields
            value = app.TransparencyType_DropDown.Value;
            % 2 - Assigning the output
            app.plot_transparency = value;
            % 3 - Printing the output
            update_txt = sprintf("-> Transparency = %s%%", sprintf(app.plot_transparency));
            disp(update_txt);
            update_myData(app);
            % - Update the live figure if required
            update_final_chamber_live(app);
        end

        % Button pushed function: ViewModel_4Panel_Button
        function ViewModel_4Panel_ButtonPushed(app, event)
            view_final_chamber_4panel(app);
        end

        % Button pushed function: ViewModel_1Panel_Button
        function ViewModel_1Panel_ButtonPushed(app, event)
            view_final_chamber_1panel(app);
        end

        % Button pushed function: ViewLive_Button
        function ViewLive_ButtonPushed(app, event)
           view_final_chamber_live(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AppButtons_GridLayout = uigridlayout(app.GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.LOAD_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.LOAD_Button.ButtonPushedFcn = createCallbackFcn(app, @LOAD_ButtonPushed, true);
            app.LOAD_Button.Tooltip = {'Load in *.txt file'};
            app.LOAD_Button.Text = {'Load File...'; '(.txt)'};
            app.SAVEAS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.SAVEAS_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVEAS_ButtonPushed, true);
            app.SAVEAS_Button.Tooltip = {'Save the current design as a *.txt & .png file'};
            app.SAVEAS_Button.Text = {'Save As... '; '(.txt & .png)'};
            app.Image = uiimage(app.AppButtons_GridLayout);
            app.Image.Tooltip = {'Effortlessly design custom vacuum chambers with precision and speed in three-dimensions using our app.'};
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Icon_SnapVac3D_App.jpeg');
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'SnapVac3D';
            app.Chamber_Panel = uipanel(app.GridLayout);
            app.Chamber_Panel.Title = 'Main-Chamber Properties';
            app.Chamber_GridLayout = uigridlayout(app.Chamber_Panel);
            app.Chamber_GridLayout.Tooltip = {'Parameter Values Tab'};
            app.Chamber_UITable = uitable(app.Chamber_GridLayout);
            app.Chamber_UITable.ColumnName = {'Name'; 'Body'; 'Outer Diameter'; 'Wall'; 'Height'; 'Notes'};
            app.Chamber_UITable.RowName = {};
            app.Chamber_UITable.ColumnEditable = true;
            app.Chamber_UITable.CellEditCallback = createCallbackFcn(app, @Chamber_UITableCellEdit, true);
            app.Chamber_UITable.Tooltip = {'Table of information and the main chamber properties'};
            app.FocalPoint_Panel = uipanel(app.GridLayout);
            app.FocalPoint_Panel.Title = 'Focal-Point Properties';
            app.FocalPoint_GridLayout = uigridlayout(app.FocalPoint_Panel);
            app.FocalPoint_GridLayout.Tooltip = {'Focal-Point Properties'};
            app.FocalPoint_UITable = uitable(app.FocalPoint_GridLayout);
            app.FocalPoint_UITable.ColumnName = {'Name'; 'Color'; 'FPX'; 'FPY'; 'FPZ'};
            app.FocalPoint_UITable.RowName = {};
            app.FocalPoint_UITable.ColumnEditable = true;
            app.FocalPoint_UITable.CellEditCallback = createCallbackFcn(app, @FocalPoint_UITableCellEdit, true);
            app.FocalPoint_UITable.Tooltip = {'Table of all the focal-point properties'};
            app.FocalPoint_Add_Button = uibutton(app.FocalPoint_GridLayout, 'push');
            app.FocalPoint_Add_Button.ButtonPushedFcn = createCallbackFcn(app, @FocalPoint_Add_ButtonPushed, true);
            app.FocalPoint_Add_Button.Tooltip = {'Add a new focal-point'};
            app.FocalPoint_Add_Button.Text = 'Add Focal Point (+)';
            app.FocalPoint_Delete_Button = uibutton(app.FocalPoint_GridLayout, 'push');
            app.FocalPoint_Delete_Button.ButtonPushedFcn = createCallbackFcn(app, @FocalPoint_Delete_ButtonPushed, true);
            app.FocalPoint_Delete_Button.Tooltip = {'Remove a focal-point'};
            app.FocalPoint_Delete_Button.Text = 'Remove Last Focal Point (-)';
            app.Port_Panel = uipanel(app.GridLayout);
            app.Port_Panel.Title = 'Port Properties';
            app.Port_GridLayout = uigridlayout(app.Port_Panel);
            app.Port_GridLayout.Tooltip = {'Port Properties'};
            app.Port_UITable = uitable(app.Port_GridLayout);
            app.Port_UITable.ColumnName = {'Port'; 'Flange'; 'Length'; 'Theta'; 'Phi'; 'Rotatable?'; 'Tapped?'; 'Usage'; 'Focal-Point'};
            app.Port_UITable.RowName = {};
            app.Port_UITable.ColumnEditable = true;
            app.Port_UITable.CellEditCallback = createCallbackFcn(app, @Port_UITableCellEdit, true);
            app.Port_UITable.Tooltip = {'Table of all the vacuum port properties'};
            app.Port_Add_Button = uibutton(app.Port_GridLayout, 'push');
            app.Port_Add_Button.ButtonPushedFcn = createCallbackFcn(app, @Port_Add_ButtonPushed, true);
            app.Port_Add_Button.Tooltip = {''};
            app.Port_Add_Button.Text = 'Add New Port (+)';
            app.Port_DeleteRowN_Button = uibutton(app.Port_GridLayout, 'push');
            app.Port_DeleteRowN_Button.ButtonPushedFcn = createCallbackFcn(app, @Port_DeleteRowN_ButtonPushed, true);
            app.Port_DeleteRowN_Button.Tooltip = {'Remove a specific port number'};
            app.Port_DeleteRowN_Button.Text = 'Remove Specific Port (-#)';
            app.Port_Delete_Button = uibutton(app.Port_GridLayout, 'push');
            app.Port_Delete_Button.ButtonPushedFcn = createCallbackFcn(app, @Port_Delete_ButtonPushed, true);
            app.Port_Delete_Button.Tooltip = {'Remove the last port'};
            app.Port_Delete_Button.Text = 'Remove Last Port (-)';
            app.Port_AutoSort_Button = uibutton(app.Port_GridLayout, 'push');
            app.Port_AutoSort_Button.ButtonPushedFcn = createCallbackFcn(app, @Port_AutoSort_ButtonPushed, true);
            app.Port_AutoSort_Button.Tooltip = {'Auto-sort the rows of the port table'};
            app.Port_AutoSort_Button.Text = 'Auto-Sort Ports';
            app.DefinitionOfAxes_Image = uiimage(app.GridLayout);
            app.DefinitionOfAxes_Image.Tooltip = {'Definition of Axes in SnapVac3D'};
            app.DefinitionOfAxes_Image.ImageSource = fullfile(pathToMLAPP, 'SnapVac3D-DefineAxes_main_01.png');
            app.Plot_GridLayout = uigridlayout(app.GridLayout);
            app.DRenderColorLabel = uilabel(app.Plot_GridLayout);
            app.DRenderColorLabel.Text = 'Render Color:';
            app.RenderColor_ColorPicker = uicolorpicker(app.Plot_GridLayout);
            app.RenderColor_ColorPicker.Value = [0.8 0.8 0.8];
            app.RenderColor_ColorPicker.ValueChangedFcn = createCallbackFcn(app, @RenderColor_ColorPickerValueChanged, true);
            app.ViewLive_Button = uibutton(app.Plot_GridLayout, 'push');
            app.ViewLive_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewLive_ButtonPushed, true);
            app.ViewLive_Button.Tooltip = {'Live view of the model which automatically updates as you change properties.'};
            app.ViewLive_Button.Text = 'Live View';
            app.ViewModel_1Panel_Button = uibutton(app.Plot_GridLayout, 'push');
            app.ViewModel_1Panel_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewModel_1Panel_ButtonPushed, true);
            app.ViewModel_1Panel_Button.Tooltip = {'View the current model (1-panel figure for isometric browsing)'};
            app.ViewModel_1Panel_Button.Text = {'View Snapshot'; '(1-Panel)'};
            app.ViewModel_4Panel_Button = uibutton(app.Plot_GridLayout, 'push');
            app.ViewModel_4Panel_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewModel_4Panel_ButtonPushed, true);
            app.ViewModel_4Panel_Button.Tooltip = {'View the current model (4-panel figure showing isometric and plane cuts through the vacuum chamber)'};
            app.ViewModel_4Panel_Button.Text = {'View Snapshot'; '(4-Panel)'};
            app.RenderTypeLabel = uilabel(app.Plot_GridLayout);
            app.RenderTypeLabel.Text = 'Render Type:';
            app.RenderType_DropDown = uidropdown(app.Plot_GridLayout);
            app.RenderType_DropDown.Items = {'Chromium', 'Faceted'};
            app.RenderType_DropDown.ValueChangedFcn = createCallbackFcn(app, @RenderType_DropDownValueChanged, true);
            app.RenderType_DropDown.Tooltip = {'Select the 3D render type;'; '  Faceted: Use flat shading with superimposed black mesh lines. '; '  Chromium: Shiny metallic finish.'};
            app.RenderType_DropDown.Value = 'Chromium';
            app.RenderTypeLabel_2 = uilabel(app.Plot_GridLayout);
            app.RenderTypeLabel_2.Text = 'Transparency:';
            app.TransparencyType_DropDown = uidropdown(app.Plot_GridLayout);
            app.TransparencyType_DropDown.Items = {'25%', '50%', '75%', '100% (Opaque)'};
            app.TransparencyType_DropDown.ValueChangedFcn = createCallbackFcn(app, @TransparencyType_DropDownValueChanged, true);
            app.TransparencyType_DropDown.Tooltip = {'Select the 3D render type;'; '  Faceted: Use flat shading with superimposed black mesh lines. '; '  Chromium: Shiny metallic finish.'};
            app.TransparencyType_DropDown.Value = '75%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_SnapVac3D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end